classdef Model_Choose1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Model     matlab.ui.Figure
        Button_5  matlab.ui.control.Button
        Panel     matlab.ui.container.Panel
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model.Name="上汽大通动力总成与电气化中心";
            app.Button_3.Enable="off";
        end

        % Close request function: Model
        function ModelCloseRequest(app, event)
            uiconfirm(app.Model,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                         evalin("base", "run QK.m")
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.Model)
            Choose_model = 1;
            assignin('base','Choose_model',Choose_model);
            run Start_Children.mlapp
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Choose_model = 2;
            assignin('base','Choose_model',Choose_model);
            delete(app.Model)
            run Optimal_Strategy\CheXing_Choose.mlapp
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.Model)
            run GUI_Start.mlapp
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            Choose_model = 4;
            assignin('base','Choose_model',Choose_model);
            uiconfirm(app.Model,"请确认是否需要进行模型校核","提示","Icon","question","Options",{'是','否'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '否'
                        delete(source);
                        run Optimal_Strategy\CheXing_Choose.mlapp;
                    case '是'
                        delete(source);
                        run Model_Verification\CheXing_Choose_jiaohe.mlapp;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Model = uifigure('Visible', 'off');
            app.Model.Name = 'MATLAB App';
            app.Model.CloseRequestFcn = createCallbackFcn(app, @ModelCloseRequest, true);
            app.Panel = uipanel(app.Model);
            app.Panel.Title = '功能选择';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '构型参数匹配';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '最优策略求解';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Text = '控制参数优化';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '能耗仿真';
            app.Button_5 = uibutton(app.Model, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '返回';
            % Show the figure after all components are created
            app.Model.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Choose1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Model)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Model)
        end
    end
end