classdef CheXing_Choose_jiaohe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Model           matlab.ui.Figure
        Button_5        matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        Button_3        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        LiangQu_jiaohe  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model.Name="上汽大通动力总成与电气化中心";
            app.Button_3.Enable="off";
        end

        % Close request function: Model
        function ModelCloseRequest(app, event)
            uiconfirm(app.Model,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                        evalin("base", "run QK.m")
                end
            end
        end

        % Button pushed function: LiangQu_jiaohe
        function LiangQu_jiaohePushed(app, event)
            delete(app.Model);    
            run Model_Verification\Model_Verification_1.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Model = uifigure('Visible', 'off');
            app.Model.Name = 'MATLAB App';
            app.Model.CloseRequestFcn = createCallbackFcn(app, @ModelCloseRequest, true);
            app.Panel = uipanel(app.Model);
            app.Panel.Title = '车型选择';
            app.LiangQu_jiaohe = uibutton(app.Panel, 'push');
            app.LiangQu_jiaohe.ButtonPushedFcn = createCallbackFcn(app, @LiangQu_jiaohePushed, true);
            app.LiangQu_jiaohe.Text = '两驱纯电动';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '四驱纯电动';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '燃料电池车';
            app.Button_5 = uibutton(app.Model, 'push');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '返回';
            % Show the figure after all components are created
            app.Model.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheXing_Choose_jiaohe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Model)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Model)
        end
    end
end