classdef Condition_Upload < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Reset_2            matlab.ui.control.Button
        Reset              matlab.ui.control.Button
        kwhJiaoheqian      matlab.ui.control.NumericEditField
        kwhLabel_2         matlab.ui.control.Label
        kwhJiaohehou       matlab.ui.control.NumericEditField
        kwhLabel           matlab.ui.control.Label
        kwhEditField       matlab.ui.control.NumericEditField
        kwhEditFieldLabel  matlab.ui.control.Label
        Button             matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            app.kwhEditField.Editable="off";
            app.kwhJiaohehou.Editable="off";
            app.kwhJiaoheqian.Editable="off";
            app.Button.FontWeight='bold';
            app.UIAxes.Title.String="校核后能耗对比图";
            app.UIAxes.Title.FontWeight="bold";
            app.UIAxes.XLabel.String="时间/s";
            app.UIAxes.YLabel.String="能耗累计/Kwh";
            app.UIAxes.FontSize=18;
            app.UIAxes.FontWeight='bold';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            evalin("base","run ../Algorithm/Model_Verify/Cal_LiangQu_JiaoHe_E.m");
            app.kwhEditField.Value = evalin('base','Cal_Origin_E');
            app.kwhJiaohehou.Value = evalin('base', 'Cal_E');
            app.kwhJiaoheqian.Value = evalin('base', 'Cal_E_wujiaohe');


            energy_verified_seq=evalin("base","Energy_seq");
            energy_not_verified_seq=evalin("base","Energy_seq_wujiaohe");
            energy_origin_seq=evalin("base","Energy_origin_seq");
            energy_origin_cumu_cal=[];
            for i=1:length(energy_origin_seq)-1
                energy_origin_cumu_cal(i)=sum(energy_origin_seq(1:i))/3600;
            end
            energy_verified_cumu_cal=[];
            for i=1:length(energy_verified_seq)
                energy_verified_cumu_cal(i)=sum(energy_verified_seq(1:i))/3600;
            end
            energy_not_verified_cumu_cal=[];
            for i=1:length(energy_not_verified_seq)
                energy_not_verified_cumu_cal(i)=sum(energy_not_verified_seq(1:i))/3600;
            end
            plot(app.UIAxes,energy_origin_cumu_cal,'-r','LineWidth',2);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,energy_verified_cumu_cal,'-b','LineWidth',2);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,energy_not_verified_cumu_cal,'-k','LineWidth',2);
            hold(app.UIAxes,"on");
            legend(app.UIAxes,"实际能耗","校核后能耗","校核后能耗",'Location','northwest');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: Reset_2
        function Reset_2ButtonPushed(app, event)
            [filename,path]=uiputfile('*.png','save figure name','figurename');
            filepath=strcat(path,filename);
            f=figure("Visible","off");
            copyobj(app.UIAxes,f);
            set(gca,'Units','normalized','Position',[0.05 0.05 0.85 0.85]);
            saveas(f,filepath);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)

            uiconfirm(app.UIFigure,"请确认是否已保存数据","完成前确认","Icon","Info","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                        evalin("base", "run QK.m")
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            uiconfirm(app.UIFigure,"关闭前请确认是否已保存数据","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始计算';
            app.kwhEditFieldLabel = uilabel(app.UIFigure);
            app.kwhEditFieldLabel.Text = '实车能耗（kwh）';
            app.kwhEditField = uieditfield(app.UIFigure, 'numeric');
            app.kwhLabel = uilabel(app.UIFigure);
            app.kwhLabel.Text = '仿真能耗（校核后）（kwh）';
            app.kwhJiaohehou = uieditfield(app.UIFigure, 'numeric');
            app.kwhLabel_2 = uilabel(app.UIFigure);
            app.kwhLabel_2.Text = '仿真能耗（校核前）（kwh）';
            app.kwhJiaoheqian = uieditfield(app.UIFigure, 'numeric');
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '结束';
            app.Reset_2 = uibutton(app.UIFigure, 'push');
            app.Reset_2.ButtonPushedFcn = createCallbackFcn(app, @Reset_2ButtonPushed, true);
            app.Reset_2.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Condition_Upload

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end