classdef Model_Verification_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Fig                   matlab.ui.Figure
        Verify_2              matlab.ui.control.Button
        Reset                 matlab.ui.control.Button
        Verify                matlab.ui.control.Button
        Panel_6               matlab.ui.container.Panel
        GridLayout3_5         matlab.ui.container.GridLayout
        Batt_kwh              matlab.ui.control.NumericEditField
        KwhLabel              matlab.ui.control.Label
        Label_22              matlab.ui.control.Label
        Batt_Data             matlab.ui.control.EditField
        Batt_Select           matlab.ui.control.DropDown
        Label_20              matlab.ui.control.Label
        Panel_7               matlab.ui.container.Panel
        Batt_Data_Browse      matlab.ui.control.Button
        Label_19              matlab.ui.control.Label
        Panel_4               matlab.ui.container.Panel
        GridLayout3_3         matlab.ui.container.GridLayout
        Motor_kw              matlab.ui.control.NumericEditField
        KwLabel               matlab.ui.control.Label
        Label_21              matlab.ui.control.Label
        Motor_Data            matlab.ui.control.EditField
        Label_18              matlab.ui.control.Label
        Motor_Select          matlab.ui.control.DropDown
        Panel_5               matlab.ui.container.Panel
        Motor_Data_Browse     matlab.ui.control.Button
        Label_14              matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        PackSOC_browse_3      matlab.ui.control.Button
        velocity              matlab.ui.control.EditField
        Label_24              matlab.ui.control.Label
        PackSOC_browse_2      matlab.ui.control.Button
        ActuPwr               matlab.ui.control.EditField
        Label_23              matlab.ui.control.Label
        PackSOC               matlab.ui.control.EditField
        SOCEditFieldLabel     matlab.ui.control.Label
        PackSOC_browse        matlab.ui.control.Button
        PackCurrVolt_browse   matlab.ui.control.Button
        PackCurrVolt          matlab.ui.control.EditField
        EditField_3Label_3    matlab.ui.control.Label
        MotorTorqSpd_browse   matlab.ui.control.Button
        MotorTorqSpd          matlab.ui.control.EditField
        Label_11              matlab.ui.control.Label
        MotorCurrVolt_browse  matlab.ui.control.Button
        MotorCurrVolt         matlab.ui.control.EditField
        Label_10              matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        hg                    matlab.ui.control.NumericEditField
        Label_7               matlab.ui.control.Label
        Forward               matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        Line                  matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        mLabel                matlab.ui.control.Label
        r_wheel               matlab.ui.control.NumericEditField
        EditField_2Label_2    matlab.ui.control.Label
        r0                    matlab.ui.control.NumericEditField
        EditField_3Label_2    matlab.ui.control.Label
        f                     matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        c_d                   matlab.ui.control.NumericEditField
        m2EditFieldLabel      matlab.ui.control.Label
        A                     matlab.ui.control.NumericEditField
        Label_9               matlab.ui.control.Label
        gravity               matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        test_m                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Fig.Name="上汽大通动力总成与电气化中心";
            assignin('base','m_f',2881);
            assignin('base','gravity',9.81);
            assignin('base','Cd',0.198);
            assignin('base','f',0.018);
            assignin('base','r0',12.1);
            assignin('base','r_wheel',0.346);
            assignin('base','Line',3.760);
            assignin('base','Forward',1.735);
            assignin('base','hg',880);
            assignin('base','A',4.75);
            app.Motor_kw.Editable="off";
            app.Batt_kwh.Editable="off";
        end

        % Value changed function: test_m
        function test_mValueChanged(app, event)
            value = app.test_m.Value;
            assignin('base','m_f',value);
        end

        % Close request function: Fig
        function FigCloseRequest(app, event)
            uiconfirm(app.Fig,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Value changed function: gravity
        function gravityValueChanged(app, event)
            value = app.gravity.Value;
            assignin('base','gravity',value);
        end

        % Value changed function: c_d
        function c_dValueChanged(app, event)
            value = app.c_d.Value;
            assignin('base','Cd',value);
        end

        % Value changed function: f
        function fValueChanged(app, event)
            value = app.f.Value;
            assignin('base','f',value);
        end

        % Value changed function: r0
        function r0ValueChanged(app, event)
            value = app.r0.Value;
            assignin('base','r0',value);
        end

        % Value changed function: r_wheel
        function r_wheelValueChanged(app, event)
            value = app.r_wheel.Value;
            assignin('base','r_wheel',value);
        end

        % Value changed function: Line
        function LineValueChanged(app, event)
            value = app.Line.Value;
            assignin('base','Line',value);
        end

        % Value changed function: Forward
        function ForwardValueChanged(app, event)
            value = app.Forward.Value;
            assignin('base','Forward',value);
        end

        % Value changed function: hg
        function hgValueChanged(app, event)
            value = app.hg.Value;
            assignin('base','hg',value);
        end

        % Button pushed function: MotorCurrVolt_browse
        function MotorCurrVolt_browseButtonPushed(app, event)
            [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.MotorCurrVolt.Value=" ";
            else
                app.MotorCurrVolt.Value=fullfile(path,file);
            end
            assignin('base','MotorCurrVolt_path',app.MotorCurrVolt.Value);
        end

        % Button pushed function: MotorTorqSpd_browse
        function MotorTorqSpd_browseButtonPushed(app, event)
            [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.MotorTorqSpd.Value=" ";
            else
                app.MotorTorqSpd.Value=fullfile(path,file);
            end
            assignin('base','MotorTorqSpd_path',app.MotorTorqSpd.Value);
        end

        % Button pushed function: PackCurrVolt_browse
        function PackCurrVolt_browseButtonPushed(app, event)
            [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.PackCurrVolt.Value=" ";
            else
                app.PackCurrVolt.Value=fullfile(path,file);
            end
            assignin('base','PackCurrVolt_path',app.PackCurrVolt.Value);
        end

        % Button pushed function: PackSOC_browse
        function PackSOC_browseButtonPushed(app, event)
            [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.PackSOC.Value=" ";
            else
                app.PackSOC.Value=fullfile(path,file);
            end
            assignin('base','PackSOC_path',app.PackSOC.Value); 
        end

        % Value changed function: Motor_Select
        function Motor_SelectValueChanged(app, event)
            value = app.Motor_Select.Value;
            if value ~= ""
                assignin('base','Model_isexist',1);
                app.Motor_Data_Browse.Enable="off";
                app.Motor_Data.Enable="off";
                app.Motor_Data.Editable="off";
                assignin('base','Motor_Model',value);
                evalin("base","run ../Algorithm/Model_Verify/Pre_Data_Processing.m");
                app.Motor_kw.Value=evalin("base","Motor_Model_struct.mc_PE");
            elseif value==""
                app.Motor_Data_Browse.Enable="on";
                app.Motor_Data.Enable="on";
                app.Motor_Data.Editable="on";
                
            end
        end

        % Value changed function: Batt_Select
        function Batt_SelectValueChanged(app, event)
            value = app.Batt_Select.Value;
            if value ~= ""
                assignin('base','Model_isexist',3);
                app.Batt_Data_Browse.Enable="off";
                app.Batt_Data.Enable="off";
                app.Batt_Data.Editable="off";
                assignin('base','Batt_Model',value);
                evalin("base","run ../Algorithm/Model_Verify/Pre_Data_Processing.m");
                app.Batt_kwh.Value=evalin("base","Batt_Model_struct.E");
            elseif value==""
                app.Batt_Data_Browse.Enable="on";
                app.Batt_Data.Enable="on";
                app.Batt_Data.Editable="on";
            end
        end

        % Button pushed function: Motor_Data_Browse
        function Motor_Data_BrowseButtonPushed(app, event)
            app.Motor_Select.Enable="off";
            assignin('base','Model_isexist',2);
            [file,path]=uigetfile({'*.m';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.Motor_Data.Value=" ";
            else
                app.Motor_Data.Value=fullfile(path,file);
            end
            assignin('base','Motor_Model_path',app.Motor_Data.Value);
            assignin("base","Motor_Model",file);
            evalin("base","run ../Algorithm/Model_Verify/Pre_Data_Processing.m");
            app.Motor_kw.Value=evalin("base","Motor_Model_struct.mc_PE");
        end

        % Button pushed function: Batt_Data_Browse
        function Batt_Data_BrowseButtonPushed(app, event)
            app.Batt_Select.Enable="off";
            assignin('base','Model_isexist',4);
            [file,path]=uigetfile({'*.m';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.Batt_Data.Value=" ";
            else
                app.Batt_Data.Value=fullfile(path,file);
            end
            assignin('base','Batt_Model_path',app.Batt_Data.Value);
            assignin("base","Batt_Model",file);
            evalin("base","run ../Algorithm/Model_Verify/Pre_Data_Processing.m");
            app.Batt_kwh.Value=evalin("base","Batt_Model_struct.E");
        end

        % Button pushed function: Verify
        function VerifyButtonPushed(app, event)
            evalin("base","clear");
            delete(app.Fig);
            run Model_Verification\Model_Verification_1.mlapp;
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            delete(app.Fig)
            run Model_Verification\Model_Verification_2.mlapp
        end

        % Button pushed function: PackSOC_browse_2
        function PackSOC_browse_2ButtonPushed(app, event)
             [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.ActuPwr.Value=" ";
            else
                app.ActuPwr.Value=fullfile(path,file);
            end
            assignin('base','ActuPwr_path',app.ActuPwr.Value);
        end

        % Value changed function: A
        function AValueChanged(app, event)
            value = app.A.Value;
            assignin('base','A',value);
        end

        % Button pushed function: PackSOC_browse_3
        function PackSOC_browse_3ButtonPushed(app, event)
            [file,path]=uigetfile({'*.ascii';'*.*'},'Select One File','MultiSelect','off');
            if isequal(file,0)
                app.velocity.Value=" ";
            else
                app.velocity.Value=fullfile(path,file);
            end
            assignin('base','velocity_path',app.velocity.Value);
        end

        % Button pushed function: Verify_2
        function Verify_2ButtonPushed(app, event)
            delete(app.Fig);
            run Model_Verification\CheXing_Choose.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Fig = uifigure('Visible', 'off');
            app.Fig.Name = 'MATLAB App';
            app.Fig.CloseRequestFcn = createCallbackFcn(app, @FigCloseRequest, true);
            app.Panel = uipanel(app.Fig);
            app.Panel.Title = '参数输入';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.test_m = uieditfield(app.GridLayout2, 'numeric');
            app.test_m.ValueChangedFcn = createCallbackFcn(app, @test_mValueChanged, true);
            app.test_m.Value = 2881;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '测试工况车身重量/kg';
            app.gravity = uieditfield(app.GridLayout2, 'numeric');
            app.gravity.ValueChangedFcn = createCallbackFcn(app, @gravityValueChanged, true);
            app.gravity.Value = 9.81;
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '重力加速度';
            app.A = uieditfield(app.GridLayout2, 'numeric');
            app.A.ValueChangedFcn = createCallbackFcn(app, @AValueChanged, true);
            app.A.Value = 4.75;
            app.m2EditFieldLabel = uilabel(app.GridLayout2);
            app.m2EditFieldLabel.Text = '迎风面积m2';
            app.c_d = uieditfield(app.GridLayout2, 'numeric');
            app.c_d.ValueChangedFcn = createCallbackFcn(app, @c_dValueChanged, true);
            app.c_d.Value = 0.198;
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '风阻系数';
            app.f = uieditfield(app.GridLayout2, 'numeric');
            app.f.ValueChangedFcn = createCallbackFcn(app, @fValueChanged, true);
            app.f.Value = 0.018;
            app.EditField_3Label_2 = uilabel(app.GridLayout2);
            app.EditField_3Label_2.Text = '滚动阻力系数';
            app.r0 = uieditfield(app.GridLayout2, 'numeric');
            app.r0.ValueChangedFcn = createCallbackFcn(app, @r0ValueChanged, true);
            app.r0.Value = 12.1;
            app.EditField_2Label_2 = uilabel(app.GridLayout2);
            app.EditField_2Label_2.Text = '主减速比';
            app.r_wheel = uieditfield(app.GridLayout2, 'numeric');
            app.r_wheel.ValueChangedFcn = createCallbackFcn(app, @r_wheelValueChanged, true);
            app.r_wheel.Value = 0.346;
            app.mLabel = uilabel(app.GridLayout2);
            app.mLabel.Text = '车轮半径/m';
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '轴距/mm';
            app.Line = uieditfield(app.GridLayout2, 'numeric');
            app.Line.ValueChangedFcn = createCallbackFcn(app, @LineValueChanged, true);
            app.Line.Value = 3760;
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '前轴到质心距离/mm';
            app.Forward = uieditfield(app.GridLayout2, 'numeric');
            app.Forward.ValueChangedFcn = createCallbackFcn(app, @ForwardValueChanged, true);
            app.Forward.Value = 1735;
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '质心高度/mm';
            app.hg = uieditfield(app.GridLayout2, 'numeric');
            app.hg.ValueChangedFcn = createCallbackFcn(app, @hgValueChanged, true);
            app.hg.Value = 880;
            app.Panel_2 = uipanel(app.Fig);
            app.Panel_2.Title = '实测数据上传';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '电机电流电压数据文件上传';
            app.MotorCurrVolt = uieditfield(app.GridLayout3, 'text');
            app.MotorCurrVolt_browse = uibutton(app.GridLayout3, 'push');
            app.MotorCurrVolt_browse.ButtonPushedFcn = createCallbackFcn(app, @MotorCurrVolt_browseButtonPushed, true);
            app.MotorCurrVolt_browse.Text = '浏览';
            app.Label_11 = uilabel(app.GridLayout3);
            app.Label_11.Text = '电机转矩转速数据文件上传';
            app.MotorTorqSpd = uieditfield(app.GridLayout3, 'text');
            app.MotorTorqSpd_browse = uibutton(app.GridLayout3, 'push');
            app.MotorTorqSpd_browse.ButtonPushedFcn = createCallbackFcn(app, @MotorTorqSpd_browseButtonPushed, true);
            app.MotorTorqSpd_browse.Text = '浏览';
            app.EditField_3Label_3 = uilabel(app.GridLayout3);
            app.EditField_3Label_3.Text = '电池包电流电压数据文件上传';
            app.PackCurrVolt = uieditfield(app.GridLayout3, 'text');
            app.PackCurrVolt_browse = uibutton(app.GridLayout3, 'push');
            app.PackCurrVolt_browse.ButtonPushedFcn = createCallbackFcn(app, @PackCurrVolt_browseButtonPushed, true);
            app.PackCurrVolt_browse.Text = '浏览';
            app.PackSOC_browse = uibutton(app.GridLayout3, 'push');
            app.PackSOC_browse.ButtonPushedFcn = createCallbackFcn(app, @PackSOC_browseButtonPushed, true);
            app.PackSOC_browse.Text = '浏览';
            app.SOCEditFieldLabel = uilabel(app.GridLayout3);
            app.SOCEditFieldLabel.Text = '电池包SOC数据文件上传';
            app.PackSOC = uieditfield(app.GridLayout3, 'text');
            app.Label_23 = uilabel(app.GridLayout3);
            app.Label_23.Text = '附件功率数据文件上传';
            app.ActuPwr = uieditfield(app.GridLayout3, 'text');
            app.PackSOC_browse_2 = uibutton(app.GridLayout3, 'push');
            app.PackSOC_browse_2.ButtonPushedFcn = createCallbackFcn(app, @PackSOC_browse_2ButtonPushed, true);
            app.PackSOC_browse_2.Text = '浏览';
            app.Label_24 = uilabel(app.GridLayout3);
            app.Label_24.Text = '工况车速数据文件上传';
            app.velocity = uieditfield(app.GridLayout3, 'text');
            app.PackSOC_browse_3 = uibutton(app.GridLayout3, 'push');
            app.PackSOC_browse_3.ButtonPushedFcn = createCallbackFcn(app, @PackSOC_browse_3ButtonPushed, true);
            app.PackSOC_browse_3.Text = '浏览';
            app.Panel_4 = uipanel(app.Fig);
            app.Panel_4.Title = '电机型号选择/上传';
            app.GridLayout3_3 = uigridlayout(app.Panel_4);
            app.Label_14 = uilabel(app.GridLayout3_3);
            app.Label_14.Text = '电机型号选择';
            app.Motor_Data_Browse = uibutton(app.GridLayout3_3, 'push');
            app.Motor_Data_Browse.ButtonPushedFcn = createCallbackFcn(app, @Motor_Data_BrowseButtonPushed, true);
            app.Motor_Data_Browse.Text = '浏览';
            app.Panel_5 = uipanel(app.GridLayout3_3);
            app.Panel_5.Title = '实测数据上传';
            app.Motor_Select = uidropdown(app.GridLayout3_3);
            app.Motor_Select.Items = {'', 'Motor_Pe_50', 'Motor_Pe_60', 'Motor_Pe_70', 'Motor_Pe_80'};
            app.Motor_Select.ValueChangedFcn = createCallbackFcn(app, @Motor_SelectValueChanged, true);
            app.Motor_Select.Value = '';
            app.Label_18 = uilabel(app.GridLayout3_3);
            app.Label_18.Text = '电机数据上传';
            app.Motor_Data = uieditfield(app.GridLayout3_3, 'text');
            app.Label_21 = uilabel(app.GridLayout3_3);
            app.Label_21.Text = '已选择电机额定功率';
            app.KwLabel = uilabel(app.GridLayout3_3);
            app.KwLabel.Text = '(Kw)';
            app.Motor_kw = uieditfield(app.GridLayout3_3, 'numeric');
            app.Panel_6 = uipanel(app.Fig);
            app.Panel_6.Title = '电池型号选择/上传';
            app.GridLayout3_5 = uigridlayout(app.Panel_6);
            app.Label_19 = uilabel(app.GridLayout3_5);
            app.Label_19.Text = '电池型号选择';
            app.Batt_Data_Browse = uibutton(app.GridLayout3_5, 'push');
            app.Batt_Data_Browse.ButtonPushedFcn = createCallbackFcn(app, @Batt_Data_BrowseButtonPushed, true);
            app.Batt_Data_Browse.Text = '浏览';
            app.Panel_7 = uipanel(app.GridLayout3_5);
            app.Panel_7.Title = '实测数据上传';
            app.Label_20 = uilabel(app.GridLayout3_5);
            app.Label_20.Text = '电池数据上传';
            app.Batt_Select = uidropdown(app.GridLayout3_5);
            app.Batt_Select.Items = {'', 'Batt_31', 'Batt_35', 'Batt_42', 'Batt_61', 'Batt_77', 'Batt_88', 'Batt_525', 'Batt_885', 'Batt_4186', 'Batt_5023'};
            app.Batt_Select.ValueChangedFcn = createCallbackFcn(app, @Batt_SelectValueChanged, true);
            app.Batt_Select.Value = '';
            app.Batt_Data = uieditfield(app.GridLayout3_5, 'text');
            app.Label_22 = uilabel(app.GridLayout3_5);
            app.Label_22.Text = '已选择电池电量';
            app.KwhLabel = uilabel(app.GridLayout3_5);
            app.KwhLabel.Text = '(Kwh)';
            app.Batt_kwh = uieditfield(app.GridLayout3_5, 'numeric');
            app.Verify = uibutton(app.Fig, 'push');
            app.Verify.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.Verify.Text = '重置页面';
            app.Reset = uibutton(app.Fig, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '继续';
            app.Verify_2 = uibutton(app.Fig, 'push');
            app.Verify_2.ButtonPushedFcn = createCallbackFcn(app, @Verify_2ButtonPushed, true);
            app.Verify_2.Text = '返回';
            % Show the figure after all components are created
            app.Fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Verification_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Fig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Fig)
        end
    end
end