classdef Model_Verification_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Reset_3          matlab.ui.control.Button
        Reset_2          matlab.ui.control.Button
        Reset            matlab.ui.control.Button
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        Image_2          matlab.ui.control.Image
        Image            matlab.ui.control.Image
        Tab_2            matlab.ui.container.Tab
        Label_4          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Image_4          matlab.ui.control.Image
        Image_3          matlab.ui.control.Image
        Results          matlab.ui.control.Button
        Data_Processing  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            uiconfirm(app.UIFigure,"关闭前请确认是否已保存数据","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Data_Processing
        function Data_ProcessingButtonPushed(app, event)
            evalin("base","run ../Algorithm/Model_Verify/Data_Processing.m");
            message={'数据清洗已完成！','结果已保存至+AppResult相应位置','请点击"开始校核"按钮开始模型校核','Success'};
            uialert(app.UIFigure,message,'Success','Icon','success');
            app.Image.ImageSource='..\+AppResult\+Figure\+Model_Verification\Data_Clear\Data_Motor_processed.jpg';
            app.Image_2.ImageSource='..\+AppResult\+Figure\+Model_Verification\Data_Clear\Data_Batt_processed.jpg';
        end

        % Button pushed function: Results
        function ResultsButtonPushed(app, event)
            evalin("base","run ../Algorithm/Model_Verify/GA_Motor.m")
            evalin("base","run ../Algorithm/Model_Verify/GA_Batt.m")
            message={'数据校核已完成！','结果已保存至+AppResult相应位置','请查看结果并保存','Success'};
            uialert(app.UIFigure,message,'Success','Icon','success');
            app.Image_3.ImageSource='..\+AppResult\+Figure\+Model_Verification\Motor\电机功率校核结果.png';
            app.Image_4.ImageSource='..\+AppResult\+Figure\+Model_Verification\Batt\蓄电池校核.png';

        end

        % Button pushed function: Reset_2
        function Reset_2ButtonPushed(app, event)
            message={'数据已保存！','结果已保存至+AppResult相应位置','Success',};
            uialert(app.UIFigure,message,'Success','Icon','success');
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
             uiconfirm(app.UIFigure,"请选择下一步工作","提示","Icon","question","Options",{'结束任务','能耗仿真'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '结束任务'
                        delete(source);
                    case '能耗仿真'
                        delete(source);
                        run Model_Verification\Condition_Upload.mlapp;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Data_Processing = uibutton(app.UIFigure, 'push');
            app.Data_Processing.ButtonPushedFcn = createCallbackFcn(app, @Data_ProcessingButtonPushed, true);
            app.Data_Processing.Text = '数据清洗';
            app.Results = uibutton(app.UIFigure, 'push');
            app.Results.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.Results.Text = '开始校核';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '数据清洗结果';
            app.Image = uiimage(app.Tab);
            app.Image_2 = uiimage(app.Tab);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '电机数据清洗结果';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '电池数据清洗结果';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '数据校核结果';
            app.Image_3 = uiimage(app.Tab_2);
            app.Image_4 = uiimage(app.Tab_2);
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '电机数据校核结果';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '电池数据校核结果';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '继续';
            app.Reset_2 = uibutton(app.UIFigure, 'push');
            app.Reset_2.ButtonPushedFcn = createCallbackFcn(app, @Reset_2ButtonPushed, true);
            app.Reset_2.Text = '保存';
            app.Reset_3 = uibutton(app.UIFigure, 'push');
            app.Reset_3.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Verification_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end