classdef CheXing_Choose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Model     matlab.ui.Figure
        Button_5  matlab.ui.control.Button
        Panel     matlab.ui.container.Panel
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model.Name="上汽大通动力总成与电气化中心";
            app.Button_3.Enable="off";
        end

        % Close request function: Model
        function ModelCloseRequest(app, event)
            uiconfirm(app.Model,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                         evalin("base", "run QK.m")
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Liangqu = 1;
            assignin('base', 'Liangqu',Liangqu);
            Siqu = 0;
            assignin('base', 'Siqu',Siqu);
            delete(app.Model)
            run Optimal_Strategy\Single_Motor_Par.mlapp
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Choose_model=  evalin('base', 'Choose_model');
            Liangqu = 0;
            assignin('base', 'Liangqu',Liangqu);
            Siqu = 1;
            assignin('base', 'Siqu',Siqu);
            if Choose_model==2
                delete(app.Model)
                run para_motor\Graphical_User_Interface\vehicle_parameter.mlapp
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.Model)
            run GUI_Start.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Model = uifigure('Visible', 'off');
            app.Model.Name = 'MATLAB App';
            app.Model.CloseRequestFcn = createCallbackFcn(app, @ModelCloseRequest, true);
            app.Panel = uipanel(app.Model);
            app.Panel.Title = '车型选择';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '两驱纯电动';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '四驱纯电动';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Text = '燃料电池车';
            app.Button_5 = uibutton(app.Model, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '返回';
            % Show the figure after all components are created
            app.Model.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheXing_Choose

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Model)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Model)
        end
    end
end