classdef LiangQuMoreGearComparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ZhiQu_E             matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        LiangQuMoreGear_DP  matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        Button              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, 'Title', '弹窗标题', 'Message', '正在计算。', 'Indeterminate', 'on');
            evalin('base', 'LiangQu_MoreGear.Example.EV_3S_SJTU.SingleEV_3S_SJTU')
            evalin('base', 'run Cal_LiangQu_E.m')
            app.LiangQuMoreGear_DP.Value=evalin('base','Fuel');
            MoreGear_E = evalin('base','Fuel');
            app.ZhiQu_E.Value = evalin('base','Cal_E');
            Zhiqu_E = evalin('base','Cal_E');
                % 模拟一些处理时间
%     pause(3);

            % 关闭弹窗
            close(d);
            if MoreGear_E - Zhiqu_E <=-0.05
                savingrate = MoreGear_E - Zhiqu_E;
                test = '推荐使用该多档箱';
                hmsg=msgbox([test],'Success!');
            elseif MoreGear_E - Zhiqu_E >-0.05 &(MoreGear_E - Zhiqu_E)<=0
                savingrate = abs((MoreGear_E - Zhiqu_E)/Zhiqu_E);
                test = '使用该多档箱百公里节能' + num2str(savingrate) +',较低，建议更换多档箱或直驱';
                hmsg=msgbox([test],'Success!');
            else
                test = '不推荐使用该多档箱，建议更换多档箱或直驱' ;
                hmsg=msgbox([test],'Success!');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(app,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(app);
                        evalin("base", "run QK.m")

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'两驱电动车多档节能潜力'; '(kwh/100km)'};
            app.LiangQuMoreGear_DP = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'两驱电动车直驱能耗'; '(kwh/100km)'};
            app.ZhiQu_E = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiangQuMoreGearComparison

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end