classdef LiangQu_KeyMotorWorkPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        xlsxLabel           matlab.ui.control.Label
        Parameters_browse   matlab.ui.control.Button
        Parameters_display  matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Button pushed function: Parameters_browse
        function Parameters_browseButtonPushed(app, event)
            [file,path]=uigetfile({'*.xlsx';'*.*'},'Select One or More Files','MultiSelect','off');
            if isequal(file,0)
                app.Parameters_display.Value=" ";
            else
                app.Parameters_display.Value=fullfile(path,file);
            end
            assignin('base','parameters_Path',app.Parameters_display.Value);
            assignin('base','parameters_name',file);
            [~,~,extension] = fileparts(file);
%             if strcmpi(extension, '.xlsx')
%                 Wltp=xlsread(app.Parameters_display.Value);
%                 x=Wltp(:,1);
%                 y=Wltp(:,2);
%                 plot(app.UIAxes,x,y);
%                 assignin('base','Condition_length',length(y));
%             elseif strcmpi(extension, '.mat')
%                 y=load(app.condition_display.Value);
%                 var_value=y.velocity;
%                 x=(1:1:length(var_value));
%                 plot(app.UIAxes,x,var_value);
%                 assignin('base','Condition_length',length(var_value));
%             end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            evalin("base", "run ANALYSEcondition.m")
            
            XMesh = evalin('base', 'XMesh');
            YMesh = evalin('base', 'YMesh');
            ZMesh = evalin('base', 'ZMesh');
%                 h = evalin('base','h');
            colorList = evalin('base','colorList');
%             axesHandle = app.UIAxes;
            colormap(colorList)
            contourf(app.UIAxes, XMesh,YMesh,ZMesh);
%             copyobj(h, app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [filename,path]=uiputfile('*.mat','save file name','Point_density');
            filepath=strcat(path,filename);
            Point_density=evalin("base","ZMesh");
            save(filepath,'Point_density');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('确定要关闭应用吗？', ...
                     '关闭确认', ...
                     '是', '否', '否');
            switch selection
                case '是'
                    delete(app.UIFigure);
                case '否'
                    return;
            end
            delete(app)
            evalin('base','run QK.m')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '参数文件';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '参数文件上传';
            app.Parameters_display = uieditfield(app.Panel, 'text');
            app.Parameters_browse = uibutton(app.Panel, 'push');
            app.Parameters_browse.ButtonPushedFcn = createCallbackFcn(app, @Parameters_browseButtonPushed, true);
            app.Parameters_browse.Text = '浏览';
            app.xlsxLabel = uilabel(app.Panel);
            app.xlsxLabel.Text = '支持xlsx文件';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '求解重要工作点';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiangQu_KeyMotorWorkPoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end