classdef LiangQu_UploadFactory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        Label_6          matlab.ui.control.Label
        confirm          matlab.ui.control.Button
        Panel_2          matlab.ui.container.Panel
        battery_display  matlab.ui.control.EditField
        Label_5          matlab.ui.control.Label
        gear_display     matlab.ui.control.EditField
        Label_4          matlab.ui.control.Label
        motor_display    matlab.ui.control.EditField
        Label_3          matlab.ui.control.Label
        ListBox          matlab.ui.control.ListBox
        ListBoxLabel     matlab.ui.control.Label
        Button           matlab.ui.control.Button
        Gear             matlab.ui.control.Button
        ListBox_Gear     matlab.ui.control.ListBox
        Label_2          matlab.ui.control.Label
        Battery          matlab.ui.control.Button
        ListBox_battery  matlab.ui.control.ListBox
        Label            matlab.ui.control.Label
        Button_go_on     matlab.ui.control.Button
        Button_go_back   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_go_on
        function Button_go_onPushed(app, event)
            
           delete(app.UIFigure)

            assignin('base', "find_Gear",'Gear');
            find_gear = evalin('base','find_Gear')
            if evalin('base', 'Choose_model') == 2
                run LiangQuMoreGearComparison.mlapp;
            elseif evalin('base', 'Choose_model') == 4
                run LiangQu_Energy_Cal.mlapp;
            end
        end

        % Button pushed function: Battery
        function BatteryPushed(app, event)
            assignin("base","Factory_flag",11);
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','on');
            fullpath=strcat(path,file);
            assignin('base', "Batt_path",fullpath);
            evalin('base','run Factory_Upload.m');
            localBatt_name = evalin('base', 'Batt');
            

            app.battery_display.Value = localBatt_name{1,1}.name;
            app.ListBox_battery.Items = evalin('base', '{Batt{1,1}.name}');
        end

        % Button pushed function: Button_go_back
        function Button_go_backPushed(app, event)
            delete(app.UIFigure);
            run Graphical_User_Interface\vehicle_parameter.mlapp;
        end

        % Callback function
        function ListBox_motorValueChanged(app, event)
            value = app.ListBox_motor.Value;
            app.motor_display.Value = value;
            path = '.\Motor_battery\motor\';
            fullpath=strcat(path,value);
            assignin('base', 'Front_Motor_path',fullpath);
            app.Button_go_on.Enable='off';
        end

        % Callback function
        function front_motor_displayValueChanged(app, event)
            app.motor_display.Value = '禁止手动输入';
            app.motor_display.Editable="off";
            app.Button_go_on.Enable='off';
        end

        % Callback function
        function rear_motor_displayValueChanged(app, event)
            app.gear_display.Value = '禁止手动输入';
            app.gear_display.Editable="off";
            app.Button_go_on.Enable='off';
        end

        % Callback function
        function battery_displayValueChanged(app, event)
            app.battery_display.Value = '禁止手动输入';
            app.battery_display.Editable = "off";
            app.Button_go_on.Enable='off';
        end

        % Callback function
        function ListBox_rear_motorValueChanged(app, event)
            value = app.ListBox_rear_motor.Value;
            app.gear_display.Value = value;
            path = '.\Motor_battery\motor\';
            fullpath=strcat(path,value);
            assignin('base','Rear_Motor_path',fullpath);
            app.Button_go_on.Enable='off';
        end

        % Callback function
        function Button_add_rear_motorPushed(app, event)
             app.Button_go_on.Enable='off';
            
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "Rear_Motor_path",fullpath);
            value1 = app.ListBox_rear_motor.Items;
            t=0;
            for i=1:length(value1)
                if strcmp(value1{i},file)
                    t=1;
                end
            end
            if t==0
                app.ListBox_rear_motor.Items= [app.ListBox_rear_motor.Items,{file}];

            else
                hMsgBox = msgbox('已添加该电机', ' ');
                t1 = timer('StartDelay', 3, 'TimerFcn', @(~,~)delete(hMsgBox));
                start(t1);
            end
            newItemIndex = numel(app.ListBox_rear_motor.Items);
            app.ListBox_rear_motor.Value = file;
            app.gear_display.Value = file;
           
        end

        % Callback function
        function confirmButtonPushed(app, event)
            evalin("base","run(Front_Motor_path)");
            evalin("base","run(Rear_Motor_path)");
            evalin("base","run(battery_path)");

            assignin('base', "Front_Motor_name",app.motor_display.Value);
            assignin('base', "Rear_Motor_name",app.gear_display.Value);
            assignin('base', "battery_name",app.battery_display.Value);

            app.Button_go_on.Enable='on';
        end

        % Value changed function: ListBox_battery
        function ListBox_batteryValueChanged(app, event)
            value = app.ListBox_battery.Value;
            app.battery_display.Value = value;
            path = '.\Motor_battery\battery\';
            fullpath=strcat(path,value);
            assignin('base','battery_path',fullpath);
            app.Button_go_on.Enable='off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            assignin("base","Factory_flag",10);
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "Motor_path",fullpath);
            evalin("base","run Factory_Upload.m");
            localMotor_name = evalin('base', 'Motor')
            app.motor_display.Value = localMotor_name{1,1}.name
            app.ListBox.Items = evalin('base', '{Motor{1,1}.name}');
          
        end

        % Button pushed function: Gear
        function GearButtonPushed(app, event)
            assignin("base","Factory_flag",12);
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "Gear_path",fullpath);
            evalin('base','run Factory_Upload.m');

            localgear_name = evalin('base', 'Gear');
            app.gear_display.Value = localgear_name{1,1}.name;
            app.ListBox_Gear.Items = evalin('base', '{Gear{1,1}.name}');
        end

        % Button pushed function: confirm
        function confirmButtonPushed2(app, event)
%             evalin("base","run(Front_Motor_path)");
%             evalin("base","run(Rear_Motor_path)");
%             evalin("base","run(battery_path)");
% 
%             assignin('base', "Front_Motor_name",app.front_motor_display.Value);
%             assignin('base', "Rear_Motor_name",app.rear_motor_display.Value);
%             assignin('base', "battery_name",app.battery_display.Value);

            app.Button_go_on.Enable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button_go_back = uibutton(app.UIFigure, 'push');
            app.Button_go_back.ButtonPushedFcn = createCallbackFcn(app, @Button_go_backPushed, true);
            app.Button_go_back.Text = '返回';
            app.Button_go_on = uibutton(app.UIFigure, 'push');
            app.Button_go_on.ButtonPushedFcn = createCallbackFcn(app, @Button_go_onPushed, true);
            app.Button_go_on.Enable = 'off';
            app.Button_go_on.Text = '继续';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '电机/电池/变速箱参数选择';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '电池';
            app.ListBox_battery = uilistbox(app.Panel);
            app.ListBox_battery.Items = {};
            app.ListBox_battery.ValueChangedFcn = createCallbackFcn(app, @ListBox_batteryValueChanged, true);
            app.ListBox_battery.Value = {};
            app.Battery = uibutton(app.Panel, 'push');
            app.Battery.ButtonPushedFcn = createCallbackFcn(app, @BatteryPushed, true);
            app.Battery.Text = '添加电池文件';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '变速箱';
            app.ListBox_Gear = uilistbox(app.Panel);
            app.ListBox_Gear.Items = {};
            app.ListBox_Gear.Value = {};
            app.Gear = uibutton(app.Panel, 'push');
            app.Gear.ButtonPushedFcn = createCallbackFcn(app, @GearButtonPushed, true);
            app.Gear.Text = '添加变速箱文件';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '添加电机文件';
            app.ListBoxLabel = uilabel(app.Panel);
            app.ListBoxLabel.Text = '电机';
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Panel_2 = uipanel(app.Panel);
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '电机';
            app.motor_display = uieditfield(app.Panel_2, 'text');
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '变速箱';
            app.gear_display = uieditfield(app.Panel_2, 'text');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '电池';
            app.battery_display = uieditfield(app.Panel_2, 'text');
            app.confirm = uibutton(app.Panel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed2, true);
            app.confirm.Text = '确认';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = {'注：若是直驱，可不上传变速箱'; '直接确认即可'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiangQu_UploadFactory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end