classdef Single_Motor_optStrategy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Model     matlab.ui.Figure
        Button_5  matlab.ui.control.Button
        Panel     matlab.ui.container.Panel
        Button_6  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model.Name="上汽大通动力总成与电气化中心";
%             app.Button.Enable="off";
        end

        % Close request function: Model
        function ModelCloseRequest(app, event)
            uiconfirm(app.Model,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                         evalin("base", "run QK.m")
                end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.Model)
            run LiangQu_UploadFactory.mlapp  
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            delete(app.Model)
            run Optimal_Strategy\LiangQu_KeyMotorWorkPoint.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Model = uifigure('Visible', 'off');
            app.Model.Name = 'MATLAB App';
            app.Model.CloseRequestFcn = createCallbackFcn(app, @ModelCloseRequest, true);
            app.Panel = uipanel(app.Model);
            app.Panel.Title = '两驱纯电动汽车优化方案选择';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '设置多档箱';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '求解电机工作点重要性分布';
            app.Button_5 = uibutton(app.Model, 'push');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '返回';
            % Show the figure after all components are created
            app.Model.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Single_Motor_optStrategy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Model)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Model)
        end
    end
end