classdef Double_Para_matching_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_8                 matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        EditField               matlab.ui.control.NumericEditField
        Label_23                matlab.ui.control.Label
        hg                      matlab.ui.control.NumericEditField
        Label_22                matlab.ui.control.Label
        Forward                 matlab.ui.control.NumericEditField
        Label_21                matlab.ui.control.Label
        Line                    matlab.ui.control.NumericEditField
        Label_20                matlab.ui.control.Label
        Label_19                matlab.ui.control.Label
        Vm                      matlab.ui.control.NumericEditField
        Label_18                matlab.ui.control.Label
        delta                   matlab.ui.control.NumericEditField
        EditField_5Label        matlab.ui.control.Label
        nt                      matlab.ui.control.NumericEditField
        EditField_6Label        matlab.ui.control.Label
        Cd                      matlab.ui.control.NumericEditField
        Label_17                matlab.ui.control.Label
        Pa                      matlab.ui.control.NumericEditField
        EditField_4Label        matlab.ui.control.Label
        f                       matlab.ui.control.NumericEditField
        EditField_2Label_4      matlab.ui.control.Label
        r0                      matlab.ui.control.NumericEditField
        EditField_3Label_4      matlab.ui.control.Label
        i0                      matlab.ui.control.NumericEditField
        Label_16                matlab.ui.control.Label
        g                       matlab.ui.control.NumericEditField
        m2EditFieldLabel        matlab.ui.control.Label
        A                       matlab.ui.control.NumericEditField
        EditField_3Label_3      matlab.ui.control.Label
        GVW_n                   matlab.ui.control.NumericEditField
        EditField_2Label_3      matlab.ui.control.Label
        GVW                     matlab.ui.control.NumericEditField
        Panel_7                 matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        EditFieldLabel          matlab.ui.control.Label
        theta                   matlab.ui.control.NumericEditField
        WLTPkmEditFieldLabel_3  matlab.ui.control.Label
        L                       matlab.ui.control.NumericEditField
        sEditField_2Label       matlab.ui.control.Label
        tm                      matlab.ui.control.NumericEditField
        kmhEditField_5Label     matlab.ui.control.Label
        Vmax                    matlab.ui.control.NumericEditField
        Panel_6                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        nr_max                  matlab.ui.control.NumericEditField
        rpmLabel_2              matlab.ui.control.Label
        nf_max                  matlab.ui.control.NumericEditField
        rpmLabel                matlab.ui.control.Label
        NmLabel                 matlab.ui.control.Label
        Tr_max                  matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        Pbatt                   matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        Wtotal                  matlab.ui.control.NumericEditField
        kwLabel                 matlab.ui.control.Label
        Pf_max                  matlab.ui.control.NumericEditField
        Cal                     matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        Condition_browse        matlab.ui.control.Button
        WLTP                    matlab.ui.control.EditField
        Label_15                matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            value=[];
            assignin('base','Vmax',130);
            assignin('base','tm',16.5);
            assignin('base','theta',0.25);
            assignin('base','GVW',4500);
            assignin('base','GVW_n',2840);
            assignin('base','A',2.22);
            assignin('base','f',0.01);
            assignin('base','i0',12.7);
            assignin('base','Vp',5);
            assignin('base','tp',1);
            assignin('base','nt',0.95);
            assignin('base','Cd',0.188);
            assignin('base','L',250);
            assignin('base','delta',1.08);
            assignin('base','Vm',100);
            assignin('base','g',9.8);
            assignin('base','r0',0.346);
            assignin('base','Pa',0);
            assignin('base','Condition_Path',value);
            assignin('base','Line', 3760);
            assignin('base','Forward',1735);
            assignin('base','hg', 880);
            assignin('base','he',770);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.UIFigure)
            run Para_Matching\Double_Motor\Double_Para_matching_2.mlapp
        end

        % Button pushed function: Cal
        function CalPushed(app, event)
%             evalin("base","run ./Double_EV_V1_Modify.m");
            evalin("base","run Double_EV_V1_Modify.m");

            app.Pf_max.Value=evalin('base','P_max');
            app.Tr_max.Value=evalin('base','T_max');
            app.Wtotal.Value=evalin('base','Wtotal');
            app.Pbatt.Value=evalin('base','Pimax');
            app.nf_max.Value=evalin("base","nf_max");
            app.nr_max.Value=evalin("base","nr_max");
        end

        % Button pushed function: Condition_browse
        function Condition_browsePushed(app, event)
            [file,path]=uigetfile({'*.xlsx';'*.*'},'Select One or More Files','MultiSelect','on');
            if isequal(file,0)
                app.WLTP.Value=" ";
            else
                app.WLTP.Value=fullfile(path,file);
            end
            assignin('base','Condition_Path',app.WLTP.Value);
            Wltp=xlsread(app.WLTP.Value);
            x=Wltp(:,1);
            y=Wltp(:,2);
            plot(app.UIAxes,x,y);
        end

        % Value changed function: Vmax
        function VmaxValueChanged(app, event)
            value = app.Vmax.Value;
            % app.Vmax=str2double(value);
            assignin('base','Vmax',value);
        end

        % Value changed function: tm
        function tmValueChanged(app, event)
            value = app.tm.Value;
            % app.tm=str2double(value);
            assignin('base','tm',value);
        end

        % Value changed function: theta
        function thetaValueChanged(app, event)
            value = app.theta.Value;
            % app.theta=str2double(value);
            theta_1=atan(value);
            assignin('base','theta',theta_1);
        end

        % Value changed function: GVW
        function GVWValueChanged(app, event)
            value = app.GVW.Value;
            % app.GVW=str2double(value);
            assignin('base','GVW',value);
        end

        % Value changed function: GVW_n
        function GVW_nValueChanged(app, event)
            value = app.GVW_n.Value;
            % app.GVW_n=str2double(value);
            assignin('base','GVW_n',value);
        end

        % Value changed function: A
        function AValueChanged(app, event)
            value = app.A.Value;
            % app.A=str2double(value);
            assignin('base','A',value);
        end

        % Value changed function: f
        function fValueChanged(app, event)
            value = app.f.Value;
            % app.f=str2double(value);
            assignin('base','f',value);
        end

        % Value changed function: i0
        function i0ValueChanged(app, event)
            value = app.i0.Value;
            % app.i0=str2double(value);
            assignin('base','i0',value);
        end

        % Callback function
        function VpValueChanged(app, event)
            value = app.Vp.Value;
            % app.Vp=str2double(value);
            assignin('base','Vp',value);
        end

        % Callback function
        function tpValueChanged(app, event)
            value = app.tp.Value;
            % app.tp=str2double(value);
            assignin('base','tp',value);
        end

        % Value changed function: nt
        function ntValueChanged(app, event)
            value = app.nt.Value;
            % app.nt=str2double(value);
            assignin('base','nt',value);
        end

        % Value changed function: Cd
        function CdValueChanged(app, event)
            value = app.Cd.Value;
            % app.Cd=str2double(value);
            assignin('base','Cd',value);
        end

        % Value changed function: L
        function LValueChanged(app, event)
            value = app.L.Value;
            % app.L=str2double(value);
            assignin('base','L',value);
        end

        % Value changed function: delta
        function deltaValueChanged(app, event)
            value = app.delta.Value;
            % app.delta=str2double(value);
            assignin('base','delta',value);
        end

        % Value changed function: Vm
        function VmValueChanged(app, event)
            value = app.Vm.Value;
            % app.Vm=str2double(value);
            assignin('base','Vm',value);
        end

        % Value changed function: g
        function gValueChanged(app, event)
            value = app.g.Value;
            % app.g=str2double(value);
            assignin('base','g',value);
        end

        % Value changed function: r0
        function r0ValueChanged(app, event)
            value = app.r0.Value;
            % app.r0=str2double(value);
            assignin('base','r0',value);
        end

        % Value changed function: Pa
        function PaValueChanged(app, event)
            value = app.Pa.Value;
            % app.Pa=str2double(value);
            assignin('base','Pa',value);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            delete(app.UIFigure);
            run Model_Choose.mlapp;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            evalin("base","clear");
            delete(app.UIFigure);
            run Para_matching_1.mlapp;
        end

        % Value changed function: Line
        function LineValueChanged(app, event)
            value = app.Line.Value;

            assignin('base','Line',value);
        end

        % Value changed function: Forward
        function ForwardValueChanged(app, event)
            value = app.Forward.Value;
            assignin('base','Forward',value);
        end

        % Value changed function: hg
        function hgValueChanged(app, event)
            value = app.hg.Value;
            assignin('base','hg',value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            assignin('base','he',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '测试工况输出';
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, '时间(/s)')
            ylabel(app.UIAxes, '速度(km/h)')
            zlabel(app.UIAxes, 'Z')
            app.Label_15 = uilabel(app.Panel_3);
            app.Label_15.Text = '工况数据文件上传';
            app.WLTP = uieditfield(app.Panel_3, 'text');
            app.Condition_browse = uibutton(app.Panel_3, 'push');
            app.Condition_browse.ButtonPushedFcn = createCallbackFcn(app, @Condition_browsePushed, true);
            app.Condition_browse.Text = '浏览';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '继续';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '重置';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '返回';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = '部件参数临界值计算';
            app.GridLayout3 = uigridlayout(app.Panel_6);
            app.Cal = uibutton(app.GridLayout3, 'push');
            app.Cal.ButtonPushedFcn = createCallbackFcn(app, @CalPushed, true);
            app.Cal.Text = '计算';
            app.Pf_max = uieditfield(app.GridLayout3, 'numeric');
            app.Pf_max.Editable = 'off';
            app.kwLabel = uilabel(app.GridLayout3);
            app.kwLabel.Text = '双电机峰值功率和kw';
            app.Wtotal = uieditfield(app.GridLayout3, 'numeric');
            app.Wtotal.Editable = 'off';
            app.Label_9 = uilabel(app.GridLayout3);
            app.Label_9.Text = '电池需求能量kwh';
            app.Pbatt = uieditfield(app.GridLayout3, 'numeric');
            app.Pbatt.Editable = 'off';
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '电池峰值功率kw';
            app.Tr_max = uieditfield(app.GridLayout3, 'numeric');
            app.Tr_max.Editable = 'off';
            app.NmLabel = uilabel(app.GridLayout3);
            app.NmLabel.Text = '双电机峰值转矩和Nm';
            app.rpmLabel = uilabel(app.GridLayout3);
            app.rpmLabel.Text = '前电机峰值转速rpm';
            app.nf_max = uieditfield(app.GridLayout3, 'numeric');
            app.nf_max.ValueDisplayFormat = '%.0f';
            app.nf_max.Editable = 'off';
            app.rpmLabel_2 = uilabel(app.GridLayout3);
            app.rpmLabel_2.Text = '后电机峰值转速rpm';
            app.nr_max = uieditfield(app.GridLayout3, 'numeric');
            app.nr_max.ValueDisplayFormat = '%.0f';
            app.nr_max.Editable = 'off';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Title = '指标输入';
            app.GridLayout = uigridlayout(app.Panel_7);
            app.Vmax = uieditfield(app.GridLayout, 'numeric');
            app.Vmax.ValueChangedFcn = createCallbackFcn(app, @VmaxValueChanged, true);
            app.Vmax.Value = 130;
            app.kmhEditField_5Label = uilabel(app.GridLayout);
            app.kmhEditField_5Label.Text = '最高车速(km/h)';
            app.tm = uieditfield(app.GridLayout, 'numeric');
            app.tm.Limits = [0 Inf];
            app.tm.ValueChangedFcn = createCallbackFcn(app, @tmValueChanged, true);
            app.tm.Value = 16.5;
            app.sEditField_2Label = uilabel(app.GridLayout);
            app.sEditField_2Label.Text = '百公里加速时间s';
            app.L = uieditfield(app.GridLayout, 'numeric');
            app.L.Limits = [0 Inf];
            app.L.ValueChangedFcn = createCallbackFcn(app, @LValueChanged, true);
            app.L.Value = 250;
            app.WLTPkmEditFieldLabel_3 = uilabel(app.GridLayout);
            app.WLTPkmEditFieldLabel_3.Text = 'WLTP目标里程/km';
            app.theta = uieditfield(app.GridLayout, 'numeric');
            app.theta.ValueChangedFcn = createCallbackFcn(app, @thetaValueChanged, true);
            app.theta.Value = 0.25;
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Text = '坡起';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Title = '参数输入';
            app.GridLayout2 = uigridlayout(app.Panel_8);
            app.GVW = uieditfield(app.GridLayout2, 'numeric');
            app.GVW.Limits = [0 Inf];
            app.GVW.ValueChangedFcn = createCallbackFcn(app, @GVWValueChanged, true);
            app.GVW.Value = 4500;
            app.EditField_2Label_3 = uilabel(app.GridLayout2);
            app.EditField_2Label_3.Text = '爬坡用满载质量';
            app.GVW_n = uieditfield(app.GridLayout2, 'numeric');
            app.GVW_n.Limits = [0 Inf];
            app.GVW_n.ValueChangedFcn = createCallbackFcn(app, @GVW_nValueChanged, true);
            app.GVW_n.Value = 2840;
            app.EditField_3Label_3 = uilabel(app.GridLayout2);
            app.EditField_3Label_3.Text = '空载质量';
            app.A = uieditfield(app.GridLayout2, 'numeric');
            app.A.ValueChangedFcn = createCallbackFcn(app, @AValueChanged, true);
            app.A.Value = 2.22;
            app.m2EditFieldLabel = uilabel(app.GridLayout2);
            app.m2EditFieldLabel.Text = '迎风面积m2';
            app.g = uieditfield(app.GridLayout2, 'numeric');
            app.g.ValueChangedFcn = createCallbackFcn(app, @gValueChanged, true);
            app.g.Value = 9.81;
            app.Label_16 = uilabel(app.GridLayout2);
            app.Label_16.Text = '重力加速度';
            app.i0 = uieditfield(app.GridLayout2, 'numeric');
            app.i0.ValueChangedFcn = createCallbackFcn(app, @i0ValueChanged, true);
            app.i0.Value = 12.7;
            app.EditField_3Label_4 = uilabel(app.GridLayout2);
            app.EditField_3Label_4.Text = '主减速比';
            app.r0 = uieditfield(app.GridLayout2, 'numeric');
            app.r0.ValueChangedFcn = createCallbackFcn(app, @r0ValueChanged, true);
            app.r0.Value = 0.346;
            app.EditField_2Label_4 = uilabel(app.GridLayout2);
            app.EditField_2Label_4.Text = '车轮滚动半径';
            app.f = uieditfield(app.GridLayout2, 'numeric');
            app.f.ValueChangedFcn = createCallbackFcn(app, @fValueChanged, true);
            app.f.Value = 0.01;
            app.EditField_4Label = uilabel(app.GridLayout2);
            app.EditField_4Label.Text = '滚动阻力系数';
            app.Pa = uieditfield(app.GridLayout2, 'numeric');
            app.Pa.ValueChangedFcn = createCallbackFcn(app, @PaValueChanged, true);
            app.Label_17 = uilabel(app.GridLayout2);
            app.Label_17.Text = '附件功率kw';
            app.Cd = uieditfield(app.GridLayout2, 'numeric');
            app.Cd.ValueChangedFcn = createCallbackFcn(app, @CdValueChanged, true);
            app.Cd.Value = 0.188;
            app.EditField_6Label = uilabel(app.GridLayout2);
            app.EditField_6Label.Text = '风阻系数';
            app.nt = uieditfield(app.GridLayout2, 'numeric');
            app.nt.ValueChangedFcn = createCallbackFcn(app, @ntValueChanged, true);
            app.nt.Value = 0.95;
            app.EditField_5Label = uilabel(app.GridLayout2);
            app.EditField_5Label.Text = '综合效率值';
            app.delta = uieditfield(app.GridLayout2, 'numeric');
            app.delta.ValueChangedFcn = createCallbackFcn(app, @deltaValueChanged, true);
            app.delta.Value = 1.08;
            app.Label_18 = uilabel(app.GridLayout2);
            app.Label_18.Text = '旋转质量系数';
            app.Vm = uieditfield(app.GridLayout2, 'numeric');
            app.Vm.ValueChangedFcn = createCallbackFcn(app, @VmValueChanged, true);
            app.Vm.Value = 100;
            app.Label_19 = uilabel(app.GridLayout2);
            app.Label_19.Text = '加速度后期车速km/h';
            app.Label_20 = uilabel(app.GridLayout2);
            app.Label_20.Text = '轴距';
            app.Line = uieditfield(app.GridLayout2, 'numeric');
            app.Line.ValueChangedFcn = createCallbackFcn(app, @LineValueChanged, true);
            app.Line.Value = 3760;
            app.Label_21 = uilabel(app.GridLayout2);
            app.Label_21.Text = '前轴到质心距离';
            app.Forward = uieditfield(app.GridLayout2, 'numeric');
            app.Forward.ValueChangedFcn = createCallbackFcn(app, @ForwardValueChanged, true);
            app.Forward.Value = 1735;
            app.Label_22 = uilabel(app.GridLayout2);
            app.Label_22.Text = '质心高度（满载）';
            app.hg = uieditfield(app.GridLayout2, 'numeric');
            app.hg.ValueChangedFcn = createCallbackFcn(app, @hgValueChanged, true);
            app.hg.Value = 880;
            app.Label_23 = uilabel(app.GridLayout2);
            app.Label_23.Text = '质心高度（空载）';
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 770;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_Para_matching_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end