classdef Double_Para_matching_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        UITable           matlab.ui.control.Table
        Tab_2             matlab.ui.container.Tab
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
        Tab_3             matlab.ui.container.Tab
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        Label_8           matlab.ui.control.Label
        Button_6          matlab.ui.control.Button
        EditField_4       matlab.ui.control.EditField
        EditField_4Label  matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        mile              matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        grade             matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        t_0_100           matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        vmax              matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            assignin('base','w_vmax',0);
            assignin('base','w_grade',0);
            assignin('base','w_t_0_100',0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.UIFigure)
            run Para_Matching\Double_Motor\Double_Para_matching_Final.mlapp
            evalin('base','run pipeizuhe.m');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [filename,path]=uiputfile('*.mat','save file name','Dynamic_Rank_DM');
            filepath=strcat(path,filename);
            Dynamic_Rank_Result=evalin("base","Double_Dynamic_Rank_table");
            save(filepath,'Dynamic_Rank_Result');
        end

        % Value changed function: vmax
        function vmaxValueChanged(app, event)
            w_vmax_in = app.vmax.Value;
            assignin("base","w_vmax",w_vmax_in);
        end

        % Value changed function: grade
        function gradeValueChanged(app, event)
            w_grade_in = app.grade.Value;
            assignin("base","w_grade",w_grade_in);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            evalin('base','run DM_dynamic.m');
            hmsg=msgbox(['动力性计算已完成！'],'Success!');
        end

        % Value changed function: t_0_100
        function t_0_100ValueChanged(app, event)
            w_t_0_100_in = app.t_0_100.Value;
            assignin("base","w_t_0_100",w_t_0_100_in);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.mile.Value=evalin("base","1.0-w_vmax-w_grade-w_t_0_100");
            assignin("base","w_mile",app.mile.Value);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            evalin("base","run WeightChoose_DM.m");
%             T=readtable("./+AppResult/Double_Dynamic_Rank.csv");
            T=readtable("AppResult.Double_Dynamic_Rank.csv");

            app.UITable.Data=T;

            % 画图
            x_axis = evalin("base",'x_axis_dy');
            y_axis_grade = evalin("base",'y_axis_grade');
            y_axis_tm = evalin("base",'y_axis_tm');
            y_axis_mile = evalin("base",'y_axis_mile');
            y_axis_vmax = evalin("base",'y_axis_vmax');
            plot(app.UIAxes, x_axis, y_axis_grade, '--o', 'Markersize', 10)
            str_grade = evalin("base", 'str_grade');
            text(app.UIAxes, x_axis, y_axis_grade, cellstr(str_grade));

            plot(app.UIAxes2, x_axis, y_axis_vmax, '--o', 'Markersize', 10);
            str_vmax = evalin("base", 'str_vmax');
            text(app.UIAxes2, x_axis, y_axis_vmax, cellstr(str_vmax));

            plot(app.UIAxes3, x_axis, y_axis_mile, '--o', 'Markersize', 10);
            x_axis_mile_plot = evalin("base", 'x_axis_mile_plot');
            y_axis_mile_plot = evalin("base", 'y_axis_mile_plot');

            text(app.UIAxes3, x_axis_mile_plot, y_axis_mile_plot, num2str(y_axis_mile_plot'));


            plot(app.UIAxes4, x_axis, y_axis_tm, '--o', 'Markersize', 10);
            str_tm = evalin("base", 'str_tm');
            text(app.UIAxes4, x_axis, y_axis_tm, cellstr(str_tm));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            delete(app.UIFigure);
            run Double_Para_matching_2.mlapp;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            assignin("base","rank2Eco",value);
            assignin("base","Factory_flag",3);
            evalin("base","run Factory_Upload.m");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '动力性计算';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '指标重要性设置（0~1）';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '最大车速权重';
            app.vmax = uieditfield(app.Panel, 'numeric');
            app.vmax.Limits = [0 1];
            app.vmax.ValueChangedFcn = createCallbackFcn(app, @vmaxValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '加速时间权重';
            app.t_0_100 = uieditfield(app.Panel, 'numeric');
            app.t_0_100.Limits = [0 1];
            app.t_0_100.ValueChangedFcn = createCallbackFcn(app, @t_0_100ValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '上坡能力权重';
            app.grade = uieditfield(app.Panel, 'numeric');
            app.grade.Limits = [0 1];
            app.grade.ValueChangedFcn = createCallbackFcn(app, @gradeValueChanged, true);
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '续航里程权重';
            app.mile = uieditfield(app.Panel, 'numeric');
            app.mile.Limits = [0 1];
            app.mile.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '动力性计算';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '动力性排序';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '保存';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '返回';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '继续';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '排序结果';
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '动力性排序结果序号';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '权重计算';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '(注：数字间请用英文逗号‘，’隔开)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '动力性能排序展示';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'前电机型号'; '后电机型号'; '电池型号'; '最高车速(km/h)'; '加速时间(s)'; '坡起'; '里程(km)'; '排名'; ''};
            app.UITable.RowName = {};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '坡起+最高车速图示';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, '坡起')
            xlabel(app.UIAxes, '组合排序')
            ylabel(app.UIAxes, '坡起')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, '最高车速')
            xlabel(app.UIAxes2, '组合排序')
            ylabel(app.UIAxes2, '最高车速（km/h）')
            zlabel(app.UIAxes2, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '续航里程+百公里加速时间图示';
            app.UIAxes3 = uiaxes(app.Tab_3);
            title(app.UIAxes3, '续航里程')
            xlabel(app.UIAxes3, '组合排序')
            ylabel(app.UIAxes3, '续航里程（km）')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab_3);
            title(app.UIAxes4, '百公里加速时间')
            xlabel(app.UIAxes4, '组合排序')
            ylabel(app.UIAxes4, '加速时间（s）')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_Para_matching_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end