classdef Double_Para_matching_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_5  matlab.ui.control.Button
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        UITable   matlab.ui.control.Table
        UIAxes    matlab.ui.control.UIAxes
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            evalin("base",'NEVs_DP_DM.NEVs_DP_DM.Example.EV_3S_SJTU.EV_3S_SJTU');
%             value_Eco_show = evalin("base","Fuel")            
%             app.Eco_show.Value = evalin("base",'Fuel');
%             app.Time_show.Value=evalin("base",'Time_S');
            evalin("base", "run DM_DP_save.m")

%             T=readtable("./+AppResult/Match_select_DP_rank.csv");
            targetfolder = evalin("base", 'targetfolder');
            T = readtable(targetfolder);
            app.UITable.Data=T;
% %             x_axis = evalin("base",'x');
%             y_axis = evalin("base",'OPT_Result.J_K_1 *1000 * 0.000000277778');
%             plot(app.UIAxes2,y_axis);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
            run ./Double_Motor/Double_Para_matching_3.mlapp;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [filename,path]=uiputfile('*.csv','save file name','filename');
            filepath=strcat(path,filename);
            assignin("base","save_path",filepath);
            assignin("base","Factory_flag",9);
            evalin("base","run Factory_Upload.m");
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            uiconfirm(app.UIFigure,"请确认是否已保存数据","完成前确认","Icon","Info","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                    delete(source);
                    app=[];
                    evalin("base", "run QK.m")

                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)

%             numCurves = evalin("base",'num_picture'); % 设定需要绘制的曲线数目
% %             delete(app.UIAxes);
% %             overlayFrame = uiframe(app.Tab_2);
%             for i = 1:numCurves
%                 
%         %             value_Eco_show = evalin("base","Fuel")            
% %             app.Eco_show.Value = evalin("base",'Fuel');
% %             app.Time_show.Value=evalin("base",'Time_S');
% %                 x = linspace(0, 2*pi, 100); % 生成自变量x，例如0到2π之间的100个点
%                 x = evalin("base", 'x');
%                 xaixs = x{i};
%                 % 创建新的UIAxes组件并存储句柄
%                 newAxes = uiaxes(app.UIFigure);
%                 app.UIFigure = uifigure('Visible', 'on');
%                 app.UIAxes(i) = newAxes;
%                 
%                 % 设置UIAxes的位置和大小，您可以根据需要调整这些参数
%                 newAxes.Position = [50, 60, 300, 200];
%                 
%                 % 计算y值，这里示例使用了sin函数
%                 y = evalin("base", 'y');
%                 yaixs = y{i};
%                 
%                 % 在新的UIAxes上绘制曲线图
%                 plot(newAxes, xaixs, yaixs);
%                 title(newAxes, sprintf('Curve %d', i)); % 设置图标题
%                 xlabel(newAxes, 'X'); % 设置x轴标签
%                 ylabel(newAxes, 'Y'); % 设置y轴标签
%             end
            evalin("base", "run energy_leijitu.m")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '节能潜力计算';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始计算';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '返回';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '完成';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '组合排序展示';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'前电机型号'; '后电机型号'; '电池型号'; '最高车速(km/h)'; '加速时间(s)'; '坡起'; '里程(km)'; '排名'; '电耗(kwh/100km)'};
            app.UITable.RowName = {};
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '能耗累积图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_Para_matching_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end