classdef Para_matching_2_FCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Button_11   matlab.ui.control.Button
        Button_10   matlab.ui.control.Button
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        ListBox_9   matlab.ui.control.ListBox
        Label_9     matlab.ui.control.Label
        Button_14   matlab.ui.control.Button
        ListBox_7   matlab.ui.control.ListBox
        Label_7     matlab.ui.control.Label
        Button_17   matlab.ui.control.Button
        Tab_2       matlab.ui.container.Tab
        Button_15   matlab.ui.control.Button
        ListBox_10  matlab.ui.control.ListBox
        Label_10    matlab.ui.control.Label
        Button_16   matlab.ui.control.Button
        ListBox_8   matlab.ui.control.ListBox
        Label_8     matlab.ui.control.Label
        Tab_3       matlab.ui.container.Tab
        Button_19   matlab.ui.control.Button
        ListBox_12  matlab.ui.control.ListBox
        Label_12    matlab.ui.control.Label
        Button_18   matlab.ui.control.Button
        ListBox_11  matlab.ui.control.ListBox
        Label_11    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            value = {};
            stru = struct;
            assignin('base', "Motor",value) ;
            assignin('base', "Batt",value);
            assignin('base', "FCS",value);
            assignin('base', 'Motor_name', stru);
            assignin('base', 'Batt_name', stru);
            assignin('base', 'FCS_name',stru);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
          No_valid = evalin("base", 'no_valid');
          if No_valid == 1
             msgbox(['没有符合要求的电机，请返回修改指标或电机库！'],'Error!');   
          else
            delete(app.UIFigure)
            run Para_matching_3_FCS.mlapp
          end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
%             assignin("base","Factory_flag",1);
%             [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','on');
%             fullpath=strcat(path,file);
%             assignin('base', "Motor_path",fullpath);
%             evalin("base","run Factory_Upload.m");
%             localMotor_name = evalin('base', 'Motor_name');
%             fields =  fieldnames(localMotor_name);
%             fields = fields';
%             app.ListBox_7.Items=fields;
   assignin("base","Factory_flag",1);
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','on');
            fullpath=strcat(path,file);
            assignin('base', "Motor_path",fullpath);
            evalin("base","run Factory_Upload.m");
            localMotor_name = evalin('base', 'Motor_name');
            fields =  fieldnames(localMotor_name);
            fields = fields';
            app.ListBox_7.Items=fields;
        end

        % Callback function: Tab, Tab
        function TabButtonDown(app, event)
            value = {};
            assignin('base', "Motor",value) ;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
%             assignin("base","Factory_flag",2);
%             [file,path]=uigetfile({'*.m'},'Select Battery Files','MultiSelect','on');
%             fullpath=strcat(path,file);
%             assignin('base', "Batt_path",fullpath);
%             evalin('base','run Factory_Upload.m');
%             localBatt_name = evalin('base', 'Batt_name');
%             fields =  fieldnames(localBatt_name);
%             fields = fields';
%             app.ListBox_8.Items=fields;
            assignin("base","Factory_flag",2);
            [file,path]=uigetfile({'*.m'},'Select Battery Files','MultiSelect','on');
            fullpath=strcat(path,file);
            assignin('base', "Batt_path",fullpath);
            evalin('base','run Factory_Upload.m');
            localBatt_name = evalin('base', 'Batt_name');
            fields =  fieldnames(localBatt_name);
            fields = fields';
            app.ListBox_8.Items=fields;
%             [file,path]=uigetfile({'*.m'},'Select One File');
%             fullpath=strcat(path,file);
%             assignin('base', "path",fullpath);
%             evalin('base','run(path)');
%             localBatt_name = evalin('base', 'Batt_name');
%             fields =  fieldnames(localBatt_name);
%             fields = fields';
%             app.ListBox_8.Items=fields;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
%             %% 添加筛选电机的功能
%             evalin("base","run Motor_Select.m");
%             %% 显示筛选结果
%             localMotorValid_name = evalin('base', 'Motor_name_valid');
%             fields_valid_Motor =  fieldnames(localMotorValid_name);
%             fields_valid_Motor = fields_valid_Motor';
%             app.ListBox_9.Items=fields_valid_Motor;

            %% 显示筛选结果
              evalin("base","run Motor_Select.m");
              No_valid = evalin("base", 'no_valid');
              if No_valid == 1
                 msgbox(['没有符合要求的电机，请返回修改指标或电机库！'],'Error!');   
              else
                localMotorValid_name = evalin('base', 'Motor_name_valid');
                fields_valid_Motor =  fieldnames(localMotorValid_name);
                fields_valid_Motor = fields_valid_Motor';
                app.ListBox_9.Items=fields_valid_Motor;
              end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
%             %% 添加筛选电池的功能
%             evalin("base","run Batt_Select.m");
%             %% 显示筛选结果
%             localBattValid_name = evalin('base', 'Batt_name_valid');
%             fields_valid_Batt =  fieldnames(localBattValid_name);
%             fields_valid_Batt = fields_valid_Batt';
%             app.ListBox_10.Items=fields_valid_Batt;

            %% 显示筛选结果
            evalin("base","run Batt_Select.m");
            localBattValid_name = evalin('base', 'Batt_name_valid');
            fields_valid_Batt =  fieldnames(localBattValid_name);
            fields_valid_Batt = fields_valid_Batt';
            app.ListBox_10.Items=fields_valid_Batt;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            delete(app.UIFigure);
            run Para_matching_1_FCS.mlapp;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            assignin("base","Factory_flag",6);
            [file,path]=uigetfile({'*.m'},'Select FCS Files','MultiSelect','on');
            fullpath=strcat(path,file);
            assignin('base', "FCS_path",fullpath);
            evalin('base','run Factory_Upload.m');
            localFCS_name = evalin('base', 'FCS_name');
            fields =  fieldnames(localFCS_name);
            fields = fields';
            app.ListBox_11.Items=fields;
%               assignin("base","Factory_flag",2);
%             [file,path]=uigetfile({'*.m'},'Select Battery Files','MultiSelect','on');
%             fullpath=strcat(path,file);
%             assignin('base', "Batt_path",fullpath);
%             evalin('base','run Factory_Upload.m');
%             localBatt_name = evalin('base', 'Batt_name');
%             fields =  fieldnames(localBatt_name);
%             fields = fields';
%             app.ListBox_11.Items=fields;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            %% 显示筛选结果
                     evalin("base","run FCS_Select.m");
            localBattValid_name = evalin('base', 'FCS_name_valid');
            fields_valid_Batt =  fieldnames(localBattValid_name);
            fields_valid_Batt = fields_valid_Batt';
            app.ListBox_12.Items=fields_valid_Batt;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.Tab.Title = '电机';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.Button_17 = uibutton(app.Tab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '添加电机文件';
            app.Label_7 = uilabel(app.Tab);
            app.Label_7.Text = '电机';
            app.ListBox_7 = uilistbox(app.Tab);
            app.ListBox_7.Items = {};
            app.ListBox_7.Value = {};
            app.Button_14 = uibutton(app.Tab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '电机筛选';
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '可行电机';
            app.ListBox_9 = uilistbox(app.Tab);
            app.ListBox_9.Items = {};
            app.ListBox_9.Value = {};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '动力电池';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '电池';
            app.ListBox_8 = uilistbox(app.Tab_2);
            app.ListBox_8.Items = {};
            app.ListBox_8.Value = {};
            app.Button_16 = uibutton(app.Tab_2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '添加电池文件';
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = '可行电池';
            app.ListBox_10 = uilistbox(app.Tab_2);
            app.ListBox_10.Items = {};
            app.ListBox_10.Value = {};
            app.Button_15 = uibutton(app.Tab_2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '电池筛选';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '燃料电池';
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '燃料电池';
            app.ListBox_11 = uilistbox(app.Tab_3);
            app.ListBox_11.Items = {};
            app.ListBox_11.Value = {};
            app.Button_18 = uibutton(app.Tab_3, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '添加燃料电池文件';
            app.Label_12 = uilabel(app.Tab_3);
            app.Label_12.Text = '可行燃料电池';
            app.ListBox_12 = uilistbox(app.Tab_3);
            app.ListBox_12.Items = {};
            app.ListBox_12.Value = {};
            app.Button_19 = uibutton(app.Tab_3, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '燃料电池筛选';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '返回';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '继续';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Para_matching_2_FCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end