classdef Para_matching_Final_FCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel_2      matlab.ui.container.Panel
        UIAxes2      matlab.ui.control.UIAxes
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
        Eco_show     matlab.ui.control.NumericEditField
        g100kmLabel  matlab.ui.control.Label
        Time_show    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"关闭前请确认是否已保存数据","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            uiconfirm(app.UIFigure,"请确认是否已保存数据","完成前确认","Icon","Info","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                        clear all;
                        clc;
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [filename,path]=uiputfile('*.mat','save file name','filename');
            filepath=strcat(path,filename);
            Dynamic_Match_Result=evalin("base","Dynamic_Match_Result");
            save(filepath,'Dynamic_Match_Result');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            evalin("base","run FCS_work_change.m");
%             value_Eco_show = evalin("base","Fuel")            
            app.Eco_show.Value = evalin("base",'Fuel');
            app.Time_show.Value=evalin("base",'Time_S');

%             x_axis = evalin("base",'x');
            y_axis = evalin("base",'OPT_Result.J_K_1');
            plot(app.UIAxes2,y_axis);

        end

        % Callback function
        function Time_showValueChanged(app, event)
%             value = app.Time_show.Value;
%             app.Time_show.Value=evalin("base","Eco_show");
%             assignin("base","w_mile",app.mile.Value);            
        end

        % Callback function
        function Eco_showValueChanged(app, event)
%             app.Eco_show.Value = evalin("base","Time_show");
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
            run Para_matching_3_FCS.mlapp;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '节能潜力计算';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始计算';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数据结果';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '计算耗时(s)';
            app.Time_show = uieditfield(app.Panel, 'numeric');
            app.g100kmLabel = uilabel(app.Panel);
            app.g100kmLabel.Text = '耗氢量（g/100km）';
            app.Eco_show = uieditfield(app.Panel, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '返回';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '完成';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '能耗累积结果';
            app.UIAxes2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, '耗氢量累积（g）')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Para_matching_Final_FCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end