classdef Start_Children < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        canshu    matlab.ui.Figure
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Image     matlab.ui.control.Image
        gouxing   matlab.ui.control.DropDown
        Label_4   matlab.ui.control.Label
        chexing   matlab.ui.control.DropDown
        Label_5   matlab.ui.control.Label
    end


    properties (Access = private)
        value1,value % Description
    end

    properties (Access = public)
        m_f % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.canshu.Name="上汽大通动力总成与电气化中心";
        end

        % Close request function: canshu
        function canshuCloseRequest(app, event)
            uiconfirm(app.canshu,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Drop down opening function: gouxing
        function gouxingOpening(app, event)
            app.value1=app.chexing.Value;
            if app.value1=="EV"
                app.gouxing.Items={' ','单电机前驱','单电机后驱','双电机'};
            elseif app.value1=="FCV"
                app.gouxing.Items={' ','Standard'};
            else
                return
            end
        end

        % Value changed function: gouxing
        function gouxingValueChanged(app, event)
            app.value = app.gouxing.Value;
            if app.value=="单电机前驱"
                app.Image.ImageSource="./pic/car.png";
                assignin('base','qianqu',1);
                assignin('base','houqu',0);
            elseif app.value=="单电机后驱"
                app.Image.ImageSource="./pic/sjtu.png";
                assignin('base','qianqu',0);
                assignin('base','houqu',1);
            elseif app.value=="双电机"
                app.Image.ImageSource="./pic/car.png";
            elseif app.value=="custom"
                app.Image.ImageSource="./pic/car.png";
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %             delete(app.canshu)
            if app.value1=="EV" && app.value=="单电机前驱"
                model="ev";
                assignin("base","Model",model);
                run Para_Matching\Single_Motor\Para_matching_1.mlapp
            elseif app.value1=="EV" && app.value=="单电机后驱"
                model="ev";
                assignin("base","Model",model);
                run ara_Matching\Single_Motor\Para_matching_1.mlapp
            elseif app.value1=="EV" && app.value=="双电机"
                model="double_ev";
                assignin("base","Model",model);
                run Para_Matching\Double_Motor\Double_Para_matching_1.mlapp
            else
                model="fcev";
                assignin("base","Model",model);
                run Para_Matching\FCEV\Para_matching_1_FCS.mlapp
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.canshu = uifigure('Visible', 'off');
            app.canshu.Name = 'MATLAB App';
            app.canshu.CloseRequestFcn = createCallbackFcn(app, @canshuCloseRequest, true);
            app.Label_5 = uilabel(app.canshu);
            app.Label_5.Text = '动力形式';
            app.chexing = uidropdown(app.canshu);
            app.chexing.Items = {'', 'EV', 'FCV'};
            app.chexing.Value = 'EV';
            app.Label_4 = uilabel(app.canshu);
            app.Label_4.Text = '构型选择';
            app.gouxing = uidropdown(app.canshu);
            app.gouxing.Items = {'请下拉选择'};
            app.gouxing.DropDownOpeningFcn = createCallbackFcn(app, @gouxingOpening, true);
            app.gouxing.ValueChangedFcn = createCallbackFcn(app, @gouxingValueChanged, true);
            app.gouxing.Value = '请下拉选择';
            app.Image = uiimage(app.canshu);
            app.Image.ImageSource = 'car.png';
            app.Button_3 = uibutton(app.canshu, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '继续';
            app.Button_4 = uibutton(app.canshu, 'push');
            app.Button_4.Text = '返回';
            % Show the figure after all components are created
            app.canshu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_Children

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.canshu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.canshu)
        end
    end
end