classdef DP_result_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DPPanel_4          matlab.ui.container.Panel
        rearpicture        matlab.ui.control.UIAxes
        frontpicture       matlab.ui.control.UIAxes
        DPPanel_2          matlab.ui.container.Panel
        Label_4            matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        DPPanel            matlab.ui.container.Panel
        Label_3            matlab.ui.control.Label
        precision_select   matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        SOCEditField       matlab.ui.control.NumericEditField
        SOCEditFieldLabel  matlab.ui.control.Label
        window             matlab.ui.control.TextArea
        Label              matlab.ui.control.Label
        startcalculation   matlab.ui.control.Button
        go_back            matlab.ui.control.Button
        go_on              matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            assignin('base','SOC_Init', 0.8);
            assignin('base','precision', 5);
            
            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
                evalin("base","cd ..");
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: go_on
        function go_onPushed(app, event)
            delete(app.UIFigure)
            run rule_based_result.mlapp
            
        end

        % Button pushed function: go_back
        function go_backPushed(app, event)
            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
                evalin("base","cd ..");
            end
            delete(app.UIFigure);
%             run ./Graphical_User_Interface/motor_and_battery.mlapp
            run motor_and_battery.mlapp
        end

        % Button pushed function: startcalculation
        function startcalculationPushed(app, event)
            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
                evalin("base","cd ..");
            end

            cla(app.UIAxes);
            cla(app.frontpicture);
            cla(app.rearpicture);

            app.window.Value = 'The computer is calculating...';
            drawnow;
            evalin("base","cd('para_motor')");
            evalin("base","cd('NEVs-DP-sm')");
            evalin("base","run('Example.EV_3S_SJTU.EV_3S_SJTU.m')");
            
            condition_judge = evalin('base', 'condition_judge');
            if condition_judge == 1
                time_cal = evalin('base', 'Time_S');
                time_str = num2str(time_cal); 
                app.window.Value = sprintf('Calculation Completed!\n计算耗时：%s秒', time_str);
                
                evalin("base","run('Discussions.DP_result_four_dimension.m')");
                acceleration= evalin('base', 'acceleration');
                velocity= evalin('base', 'velocity');
                SOC= evalin('base', 'SOC');
                lambda= evalin('base', 'lambda');
                scatter3(app.UIAxes, acceleration, velocity, SOC, [], lambda, 'filled');
                colormap(app.UIAxes, jet);
                colorbar(app.UIAxes);
                grid(app.UIAxes, 'on');

                evalin("base","run('Discussions.motor_coefficient_detection.m')");
                T_m_Front_1= evalin('base', 'T_m_Front_1');
                T_m_Rear_1= evalin('base', 'T_m_Rear_1');
                W_m_Front_1= evalin('base', 'W_m_Front_1');
                W_m_Rear_1= evalin('base', 'W_m_Rear_1');
                S_1=evalin('base', 'S_1');
                S_2=evalin('base', 'S_2');
                T_1=evalin('base', 'T_1');
                T_2=evalin('base', 'T_2');
                eff_1=evalin('base', 'eff_1');
                eff_2=evalin('base', 'eff_2');
 
                value = (52:4:100);
                [C1, h1] = contourf(app.frontpicture, S_1, T_1, eff_1, value);
                clabel(C1, h1, value);
                hold(app.frontpicture, 'on');
                scatter(app.frontpicture, W_m_Front_1/(2*pi/60), T_m_Front_1,10);
                
                [C2, h2] = contourf(app.rearpicture, S_2, T_2, eff_2, value);
                clabel(C2, h2, value);
                hold(app.rearpicture, 'on');
                scatter(app.rearpicture, W_m_Rear_1/(2*pi/60), T_m_Rear_1,8);

                app.go_on.Enable='on';
            end
            if condition_judge == 0
                app.window.Value = sprintf('Calculation Failed!\n请检查动力部件是否匹配');
                app.go_on.Enable='off';
                
            end
            evalin("base","cd .."); 
            evalin("base","cd .."); 
                    
                
        end

        % Value changed function: window
        function windowValueChanged(app, event)
             
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: SOCEditField
        function SOCEditFieldValueChanged(app, event)
            value = app.SOCEditField.Value;
            assignin('base','SOC_Init', value);
        end

        % Value changed function: precision_select
        function precision_selectValueChanged(app, event)
            value = app.precision_select.Value;
            list=[10,5,4,2,1];
            assignin('base','precision', list(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.go_on = uibutton(app.UIFigure, 'push');
            app.go_on.ButtonPushedFcn = createCallbackFcn(app, @go_onPushed, true);
            app.go_on.Enable = 'off';
            app.go_on.Text = '继续';
            app.go_back = uibutton(app.UIFigure, 'push');
            app.go_back.ButtonPushedFcn = createCallbackFcn(app, @go_backPushed, true);
            app.go_back.Text = '返回';
            app.DPPanel = uipanel(app.UIFigure);
            app.DPPanel.Title = 'DP计算过程显示';
            app.startcalculation = uibutton(app.DPPanel, 'push');
            app.startcalculation.ButtonPushedFcn = createCallbackFcn(app, @startcalculationPushed, true);
            app.startcalculation.Text = '开始计算';
            app.Label = uilabel(app.DPPanel);
            app.Label.Text = '';
            app.window = uitextarea(app.DPPanel);
            app.window.ValueChangedFcn = createCallbackFcn(app, @windowValueChanged, true);
            app.SOCEditFieldLabel = uilabel(app.DPPanel);
            app.SOCEditFieldLabel.Text = '初始SOC';
            app.SOCEditField = uieditfield(app.DPPanel, 'numeric');
            app.SOCEditField.ValueChangedFcn = createCallbackFcn(app, @SOCEditFieldValueChanged, true);
            app.SOCEditField.Value = 0.8;
            app.Label_2 = uilabel(app.DPPanel);
            app.Label_2.Text = '计算精度';
            app.precision_select = uieditfield(app.DPPanel, 'numeric');
            app.precision_select.ValueChangedFcn = createCallbackFcn(app, @precision_selectValueChanged, true);
            app.precision_select.Value = 2;
            app.Label_3 = uilabel(app.DPPanel);
            app.Label_3.Text = '级';
            app.DPPanel_2 = uipanel(app.UIFigure);
            app.DPPanel_2.Title = 'DP计算结果显示';
            app.UIAxes = uiaxes(app.DPPanel_2);
            xlabel(app.UIAxes, '加速度(m/s^2)')
            ylabel(app.UIAxes, '速度(m/s)')
            zlabel(app.UIAxes, 'SOC')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Label_4 = uilabel(app.DPPanel_2);
            app.Label_4.Text = '扭矩分配系数';
            app.DPPanel_4 = uipanel(app.UIFigure);
            app.DPPanel_4.Title = 'DP计算结果显示';
            app.frontpicture = uiaxes(app.DPPanel_4);
            xlabel(app.frontpicture, '转速(n/min)')
            ylabel(app.frontpicture, '扭矩(Nm)')
            app.rearpicture = uiaxes(app.DPPanel_4);
            xlabel(app.rearpicture, '转速(n/min)')
            ylabel(app.rearpicture, '扭矩(Nm)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DP_result_display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end