classdef motor_and_battery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        confirm                 matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        battery_display         matlab.ui.control.EditField
        Label_11                matlab.ui.control.Label
        rear_motor_display      matlab.ui.control.EditField
        Label_10                matlab.ui.control.Label
        front_motor_display     matlab.ui.control.EditField
        Label_9                 matlab.ui.control.Label
        Button_add_battery      matlab.ui.control.Button
        ListBox_battery         matlab.ui.control.ListBox
        Label_8                 matlab.ui.control.Label
        ListBox_front_motor     matlab.ui.control.ListBox
        Label_7                 matlab.ui.control.Label
        Button_add_front_motor  matlab.ui.control.Button
        ListBox_rear_motor      matlab.ui.control.ListBox
        ListBox_14Label         matlab.ui.control.Label
        Button_add_rear_motor   matlab.ui.control.Button
        Button_go_on            matlab.ui.control.Button
        Button_go_back          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            
%             folder = 'para_motor\Motor_battery\motor';
%             folder = fullfile(pwd, 'Motor_battery', 'motor');
%             files = dir(fullfile(folder, '*.m'));
            % 遍历文件列表并提取文件名
%             file_names = {};
%             for i = 1:numel(files)
%                 file_names{i} = files(i).name;
%             end
            % 将文件名添加到ListBox组件中
%             app.ListBox_front_motor.Items = file_names;
%             app.ListBox_rear_motor.Items = file_names;

%             folder = 'para_motor\Motor_battery\battery';
%             files = dir(fullfile(folder, '*.m'));
%             % 遍历文件列表并提取文件名
%             file_names = {};
%             for i = 1:numel(files)
%                 file_names{i} = files(i).name;
%             end
%             % 将文件名添加到ListBox组件中
%             app.ListBox_battery.Items = file_names;
            
%             path = 'para_motor\Motor_battery\motor\';
%             front_default = 'Motor_Pe_50.m';
%             fullpath=strcat(path,front_default);
%             assignin('base', 'Front_Motor_path',fullpath);
%             assignin('base', "Front_Motor_name",front_default);

%             rear_default = 'Motor_Pe_80.m';
%             fullpath=strcat(path,rear_default);
%             assignin('base', 'Rear_Motor_path',fullpath);
%             assignin('base', "Rear_Motor_name",rear_default);

%             path = 'para_motor\Motor_battery\battery\';
%             battery_default = 'battery1.m';
%             fullpath=strcat(path,battery_default);
%             assignin('base', 'battery_path',fullpath);
%             assignin('base', "battery_name",battery_default);
            app.Button_go_on.Enable='off';

            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: Button_go_on
        function Button_go_onPushed(app, event)
            delete(app.UIFigure)
            run DP_result_display.mlapp
        end

        % Button pushed function: Button_add_front_motor
        function Button_add_front_motorPushed(app, event)
            app.Button_go_on.Enable='off';
            
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "Front_Motor_path",fullpath);
            Factory_flag = 13;
            assignin('base', "Factory_flag", Factory_flag);
            evalin('base', 'run Factory_Upload.m')
            value1 = app.ListBox_front_motor.Items;

            t=0;
            for i=1:length(value1)
                if strcmp(value1{i},file)
                    t=1;
                end
            end
            if t==0
                app.ListBox_front_motor.Items= [app.ListBox_front_motor.Items,{file}];
            else
                hMsgBox = msgbox('已添加该电机', ' ');
                t1 = timer('StartDelay', 3, 'TimerFcn', @(~,~)delete(hMsgBox));
                start(t1);
            end
            newItemIndex = numel(app.ListBox_front_motor.Items);
            app.ListBox_front_motor.Value = file;
            app.front_motor_display.Value = file;
            app.Button_go_on.Enable='off';
        end

        % Button pushed function: Button_add_battery
        function Button_add_batteryPushed(app, event)
            app.Button_go_on.Enable='off';
            
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "battery_path",fullpath);
            Factory_flag = 15;
            assignin('base', "Factory_flag", Factory_flag);
            evalin('base', 'run Factory_Upload.m')
            value1 = app.ListBox_battery.Items;
            t=0;
            for i=1:length(value1)
                if strcmp(value1{i},file)
                    t=1;
                end
            end
            if t==0
                app.ListBox_battery.Items= [app.ListBox_battery.Items,{file}];
            else
                hMsgBox = msgbox('已添加该电池', ' ');
                t1 = timer('StartDelay', 3, 'TimerFcn', @(~,~)delete(hMsgBox));
                start(t1);
            end
            newItemIndex = numel(app.ListBox_battery.Items);
            app.ListBox_battery.Value = file;
            app.battery_display.Value = file;
            
        end

        % Button pushed function: Button_go_back
        function Button_go_backPushed(app, event)
            delete(app.UIFigure);
            run vehicle_parameter.mlapp;
        end

        % Value changed function: ListBox_front_motor
        function ListBox_front_motorValueChanged(app, event)
            value = app.ListBox_front_motor.Value;
            app.front_motor_display.Value = value;
            path = 'para_motor\Motor_battery\motor\';
            fullpath=strcat(path,value);
            assignin('base', 'Front_Motor_path',fullpath);
            app.Button_go_on.Enable='off';
        end

        % Value changed function: front_motor_display
        function front_motor_displayValueChanged(app, event)
            app.front_motor_display.Value = '禁止手动输入';
            app.front_motor_display.Editable="off";
            app.Button_go_on.Enable='off';
        end

        % Value changed function: rear_motor_display
        function rear_motor_displayValueChanged(app, event)
            app.rear_motor_display.Value = '禁止手动输入';
            app.rear_motor_display.Editable="off";
            app.Button_go_on.Enable='off';
        end

        % Value changed function: battery_display
        function battery_displayValueChanged(app, event)
            app.battery_display.Value = '禁止手动输入';
            app.battery_display.Editable = "off";
            app.Button_go_on.Enable='off';
        end

        % Value changed function: ListBox_rear_motor
        function ListBox_rear_motorValueChanged(app, event)
            value = app.ListBox_rear_motor.Value;
            app.rear_motor_display.Value = value;
            path = 'para_motor\Motor_battery\motor\';
            fullpath=strcat(path,value);
            assignin('base','Rear_Motor_path',fullpath);
            app.Button_go_on.Enable='off';
        end

        % Button pushed function: Button_add_rear_motor
        function Button_add_rear_motorPushed(app, event)
             app.Button_go_on.Enable='off';
            
            [file,path]=uigetfile({'*.m'},'Select Motor Files','MultiSelect','off');
            fullpath=strcat(path,file);
            assignin('base', "Rear_Motor_path",fullpath);
            Factory_flag = 14;
            assignin('base', "Factory_flag", Factory_flag);
            evalin('base', 'run Factory_Upload.m')
            value1 = app.ListBox_rear_motor.Items;
            t=0;
            for i=1:length(value1)
                if strcmp(value1{i},file)
                    t=1;
                end
            end
            if t==0
                app.ListBox_rear_motor.Items= [app.ListBox_rear_motor.Items,{file}];

            else
                hMsgBox = msgbox('已添加该电机', ' ');
                t1 = timer('StartDelay', 3, 'TimerFcn', @(~,~)delete(hMsgBox));
                start(t1);
            end
            newItemIndex = numel(app.ListBox_rear_motor.Items);
            app.ListBox_rear_motor.Value = file;
            app.rear_motor_display.Value = file;
           
        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)
            evalin("base","run(Front_Motor_path)");
            evalin("base","run(Rear_Motor_path)");
            evalin("base","run(battery_path)");

            assignin('base', "Front_Motor_name",app.front_motor_display.Value);
            assignin('base', "Rear_Motor_name",app.rear_motor_display.Value);
            assignin('base', "battery_name",app.battery_display.Value);

            app.Button_go_on.Enable='on';
        end

        % Value changed function: ListBox_battery
        function ListBox_batteryValueChanged(app, event)
            value = app.ListBox_battery.Value;
            app.battery_display.Value = value;
            path = 'para_motor\Motor_battery\battery\';
            fullpath=strcat(path,value);
            assignin('base','battery_path',fullpath);
            app.Button_go_on.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button_go_back = uibutton(app.UIFigure, 'push');
            app.Button_go_back.ButtonPushedFcn = createCallbackFcn(app, @Button_go_backPushed, true);
            app.Button_go_back.Text = '返回';
            app.Button_go_on = uibutton(app.UIFigure, 'push');
            app.Button_go_on.ButtonPushedFcn = createCallbackFcn(app, @Button_go_onPushed, true);
            app.Button_go_on.Enable = 'off';
            app.Button_go_on.Text = '继续';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '电机及电池参数选择';
            app.Button_add_rear_motor = uibutton(app.Panel, 'push');
            app.Button_add_rear_motor.ButtonPushedFcn = createCallbackFcn(app, @Button_add_rear_motorPushed, true);
            app.Button_add_rear_motor.Text = '添加后电机文件';
            app.ListBox_14Label = uilabel(app.Panel);
            app.ListBox_14Label.Text = '后电机';
            app.ListBox_rear_motor = uilistbox(app.Panel);
            app.ListBox_rear_motor.Items = {};
            app.ListBox_rear_motor.ValueChangedFcn = createCallbackFcn(app, @ListBox_rear_motorValueChanged, true);
            app.ListBox_rear_motor.Value = {};
            app.Button_add_front_motor = uibutton(app.Panel, 'push');
            app.Button_add_front_motor.ButtonPushedFcn = createCallbackFcn(app, @Button_add_front_motorPushed, true);
            app.Button_add_front_motor.Text = '添加前电机文件';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '前电机';
            app.ListBox_front_motor = uilistbox(app.Panel);
            app.ListBox_front_motor.Items = {};
            app.ListBox_front_motor.ValueChangedFcn = createCallbackFcn(app, @ListBox_front_motorValueChanged, true);
            app.ListBox_front_motor.Value = {};
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '电池';
            app.ListBox_battery = uilistbox(app.Panel);
            app.ListBox_battery.Items = {};
            app.ListBox_battery.ValueChangedFcn = createCallbackFcn(app, @ListBox_batteryValueChanged, true);
            app.ListBox_battery.Value = {};
            app.Button_add_battery = uibutton(app.Panel, 'push');
            app.Button_add_battery.ButtonPushedFcn = createCallbackFcn(app, @Button_add_batteryPushed, true);
            app.Button_add_battery.Text = '添加电池文件';
            app.Panel_2 = uipanel(app.Panel);
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '前电机';
            app.front_motor_display = uieditfield(app.Panel_2, 'text');
            app.front_motor_display.ValueChangedFcn = createCallbackFcn(app, @front_motor_displayValueChanged, true);
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '后电机';
            app.rear_motor_display = uieditfield(app.Panel_2, 'text');
            app.rear_motor_display.ValueChangedFcn = createCallbackFcn(app, @rear_motor_displayValueChanged, true);
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '电池';
            app.battery_display = uieditfield(app.Panel_2, 'text');
            app.battery_display.ValueChangedFcn = createCallbackFcn(app, @battery_displayValueChanged, true);
            app.confirm = uibutton(app.Panel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor_and_battery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end