classdef rule_based_result < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel_10          matlab.ui.container.Panel
        export            matlab.ui.control.Button
        startcalculation  matlab.ui.control.Button
        precision_select  matlab.ui.control.NumericEditField
        Label_31          matlab.ui.control.Label
        Label_30          matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Panel_9           matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        fuel_average      matlab.ui.control.EditField
        fuel_Rule         matlab.ui.control.EditField
        fuel_DP           matlab.ui.control.EditField
        averageLabel      matlab.ui.control.Label
        RuleLabel         matlab.ui.control.Label
        DPLabel           matlab.ui.control.Label
        KWh100kmLabel     matlab.ui.control.Label
        Label_32          matlab.ui.control.Label
        go_back           matlab.ui.control.Button
        go_on             matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            
            assignin('base','precision', 8);

            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: go_on
        function go_onPushed(app, event)
            delete(app.UIFigure)
            run nextone.mlapp
            
        end

        % Button pushed function: go_back
        function go_backPushed(app, event)
            delete(app.UIFigure);
            run DP_result_display.mlapp;
        end

        % Value changed function: precision_select
        function precision_selectValueChanged(app, event)
            value = app.precision_select.Value;
            list=[6,8,12,16,20];
            assignin('base','precision', list(value));
        end

        % Button pushed function: startcalculation
        function startcalculationButtonPushed(app, event)
            evalin("base",'currentPath = pwd;');
            currentPath =  evalin('base', 'currentPath');
            a=currentPath(end-9:end);
            if strcmp(a,'NEVs-DP-sm')
                evalin("base","cd ..");
            end
            
            cla(app.UIAxes);
 
            evalin("base","cd('para_motor\NEVs-DP-sm')");
            evalin("base","run('Discussions.rule_extract_cluster.m')");
            fileID = fopen('window.txt', 'r');
            fileContent = fscanf(fileID, '%c');
            fclose(fileID);
            app.TextArea.Value = fileContent;

            Index_End = evalin('base', 'Index_End');
            SOC_DP = evalin('base', 'OPT_Result.SOC_K_1');
            SOC_Init = evalin('base', 'SOC_Init');
            SOC_DP=cat(2,SOC_Init,SOC_DP);
            SOC_save = evalin('base', 'SOC_save');
            SOC_save_5 = evalin('base', 'SOC_save_5');
            x=1:1:Index_End;

            plot(app.UIAxes, x, SOC_save, 'LineWidth', 1);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, SOC_DP, 'LineWidth', 1);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, SOC_save_5, 'LineWidth', 1);
            
            % 设置坐标轴标签和图例
            xlabel(app.UIAxes, '步长/时间(/s)');
            ylabel(app.UIAxes, 'SOC值');
            legend(app.UIAxes, 'Rule-Based', 'DP', 'average');

            Fuel = evalin('base', 'Fuel');
            Fuel_rule_based = evalin('base', 'Fuel_rule_based');
            Fuel_average = evalin('base', 'Fuel_average');
            app.fuel_DP.Value=num2str(Fuel);
            app.fuel_Rule.Value=num2str(Fuel_rule_based);
            app.fuel_average.Value=num2str(Fuel_average);

            app.go_on.Enable='on';
            evalin("base","cd ..");
            evalin("base","cd ..");
        end

        % Button pushed function: export
        function exportButtonPushed(app, event)
            evalin("base","run('Discussions.rule_export.m')");
            hMsgBox = msgbox('文件已导出到默认目录', ' ');
            t1 = timer('StartDelay', 3, 'TimerFcn', @(~,~)delete(hMsgBox));
            start(t1);
        end

        % Value changed function: fuel_DP
        function fuel_DPValueChanged(app, event)
            app.fuel_DP.Value='禁止手动输入';
            app.fuel_DP.Editable="off";
        end

        % Value changed function: fuel_Rule
        function fuel_RuleValueChanged(app, event)
            app.fuel_Rule.Value='禁止手动输入';
            app.fuel_Rule.Editable="off";
        end

        % Value changed function: fuel_average
        function fuel_averageValueChanged(app, event)
            app.fuel_average.Value='禁止手动输入';
            app.fuel_average.Editable="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '结果对比';
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, '步长/时间(/s)')
            ylabel(app.UIAxes, 'SOC')
            zlabel(app.UIAxes, 'Z')
            app.go_on = uibutton(app.UIFigure, 'push');
            app.go_on.ButtonPushedFcn = createCallbackFcn(app, @go_onPushed, true);
            app.go_on.Enable = 'off';
            app.go_on.Text = '继续';
            app.go_back = uibutton(app.UIFigure, 'push');
            app.go_back.ButtonPushedFcn = createCallbackFcn(app, @go_backPushed, true);
            app.go_back.Text = '返回';
            app.Panel_9 = uipanel(app.UIFigure);
            app.Panel_9.Title = '能耗对比';
            app.GridLayout = uigridlayout(app.Panel_9);
            app.Label_32 = uilabel(app.GridLayout);
            app.Label_32.Text = '策略';
            app.KWh100kmLabel = uilabel(app.GridLayout);
            app.KWh100kmLabel.Text = '能耗[KWh/(100km)]';
            app.DPLabel = uilabel(app.GridLayout);
            app.DPLabel.Text = 'DP';
            app.RuleLabel = uilabel(app.GridLayout);
            app.RuleLabel.Text = 'Rule';
            app.averageLabel = uilabel(app.GridLayout);
            app.averageLabel.Text = 'average';
            app.fuel_DP = uieditfield(app.GridLayout, 'text');
            app.fuel_DP.ValueChangedFcn = createCallbackFcn(app, @fuel_DPValueChanged, true);
            app.fuel_Rule = uieditfield(app.GridLayout, 'text');
            app.fuel_Rule.ValueChangedFcn = createCallbackFcn(app, @fuel_RuleValueChanged, true);
            app.fuel_average = uieditfield(app.GridLayout, 'text');
            app.fuel_average.ValueChangedFcn = createCallbackFcn(app, @fuel_averageValueChanged, true);
            app.Panel_10 = uipanel(app.UIFigure);
            app.Panel_10.Title = '规则提取';
            app.TextArea = uitextarea(app.Panel_10);
            app.Label_30 = uilabel(app.Panel_10);
            app.Label_30.Text = '级';
            app.Label_31 = uilabel(app.Panel_10);
            app.Label_31.Text = '提取精度';
            app.precision_select = uieditfield(app.Panel_10, 'numeric');
            app.precision_select.ValueChangedFcn = createCallbackFcn(app, @precision_selectValueChanged, true);
            app.precision_select.Value = 2;
            app.startcalculation = uibutton(app.Panel_10, 'push');
            app.startcalculation.ButtonPushedFcn = createCallbackFcn(app, @startcalculationButtonPushed, true);
            app.startcalculation.Text = '开始提取';
            app.export = uibutton(app.Panel_10, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.export.Text = '导出excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rule_based_result

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end