classdef vehicle_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ButtonGroup              matlab.ui.container.ButtonGroup
        mEditField               matlab.ui.control.NumericEditField
        mEditFieldLabel          matlab.ui.control.Label
        kgEditField_2            matlab.ui.control.NumericEditField
        kgEditField_2Label       matlab.ui.control.Label
        kgEditField              matlab.ui.control.NumericEditField
        kgEditFieldLabel         matlab.ui.control.Label
        confirm                  matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        Label_28                 matlab.ui.control.Label
        Panel_8                  matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        hg_empty                 matlab.ui.control.NumericEditField
        Label_23                 matlab.ui.control.Label
        hg_full                  matlab.ui.control.NumericEditField
        Label_22                 matlab.ui.control.Label
        forward_distance         matlab.ui.control.NumericEditField
        Label_21                 matlab.ui.control.Label
        wheel_base               matlab.ui.control.NumericEditField
        Label_20                 matlab.ui.control.Label
        Label_19                 matlab.ui.control.Label
        adhesion_coeff           matlab.ui.control.NumericEditField
        Label_18                 matlab.ui.control.Label
        rotation_converse_coeff  matlab.ui.control.NumericEditField
        kgLabel                  matlab.ui.control.Label
        air_density              matlab.ui.control.NumericEditField
        EditField_6Label         matlab.ui.control.Label
        wind_coeff               matlab.ui.control.NumericEditField
        Label_17                 matlab.ui.control.Label
        p_auxiliary              matlab.ui.control.NumericEditField
        EditField_4Label         matlab.ui.control.Label
        rolling_coeff            matlab.ui.control.NumericEditField
        EditField_2Label_4       matlab.ui.control.Label
        radius_wheel             matlab.ui.control.NumericEditField
        EditField_3Label_4       matlab.ui.control.Label
        slope                    matlab.ui.control.NumericEditField
        Label_16                 matlab.ui.control.Label
        gravity_acc              matlab.ui.control.NumericEditField
        m2Label                  matlab.ui.control.Label
        wind_area                matlab.ui.control.NumericEditField
        EditField_3Label_3       matlab.ui.control.Label
        mass_empty               matlab.ui.control.NumericEditField
        EditField_2Label_3       matlab.ui.control.Label
        mass_full                matlab.ui.control.NumericEditField
        Panel_7                  matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        Label_27                 matlab.ui.control.Label
        final_rear_eff           matlab.ui.control.NumericEditField
        Label_26                 matlab.ui.control.Label
        final_rear_ratio         matlab.ui.control.NumericEditField
        Label_25                 matlab.ui.control.Label
        final_front_eff          matlab.ui.control.NumericEditField
        Label_24                 matlab.ui.control.Label
        final_front_ratio        matlab.ui.control.NumericEditField
        re_turn                  matlab.ui.control.Button
        reset                    matlab.ui.control.Button
        go_on                    matlab.ui.control.Button
        Panel_3                  matlab.ui.container.Panel
        xlsxmatLabel             matlab.ui.control.Label
        Condition_browse         matlab.ui.control.Button
        condition_display        matlab.ui.control.EditField
        Label_15                 matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="上汽大通动力总成与电气化中心";
            value=[];
            assignin('base','final_front_ratio',12.7);
            assignin('base','final_front_eff',0.95);
            assignin('base','final_rear_ratio',12.7);
            assignin('base','final_rear_eff',0.95);
            assignin('base','mass_full',4500);
            assignin('base','mass_empty',2840);
            assignin('base','wind_area',2.22);
            assignin('base','gravity_acc',9.81);
            assignin('base','slope',0);
            assignin('base','radius_wheel',0.346);
            assignin('base','rolling_coeff',0.01);
            assignin('base','p_auxiliary',1000);
            assignin('base','wind_coeff',0.188);
            assignin('base','air_density',1.2);
            assignin('base','rotation_converse_coeff',1.08);
            assignin('base','adhesion_coeff',0.8);
            assignin('base','wheel_base',3.76);
            assignin('base','forward_distance',1.735);
%             assignin('base','Pa',0);
%             assignin('base','Condition_Path',value);
            assignin('base','hg_full', 0.88);
            assignin('base','hg_empty',0.77);
            app.DropDown.Value = "请下拉选择";
            DropDownValueChanged(app);
            
           
%             y=load('para_motor\NEVs-DP-sm\+CycleData\CLTC.mat');
%             var_value=y.velocity;
%             x=(1:1:length(var_value));
%             plot(app.UIAxes,x,var_value);
% %             evalin("base","cd ..");
% 
%             assignin('base','Condition_name','CLTC');
%             assignin('base','Condition_length',1800);
            app.go_on.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,"请确认是否需要关闭当前窗口","关闭前确认","Options",{'确认','取消'},"DefaultOption",2,"CancelOption",2,"CloseFcn",@CloseFcnForFigure);
            function CloseFcnForFigure(source,event)
                switch event.SelectedOption
                    case '取消'
                    case '确认'
                        delete(source);
                        app=[];
                end
            end
        end

        % Button pushed function: go_on
        function go_onPushed(app, event)
            delete(app.UIFigure)

            run("motor_and_battery.mlapp")
        end

        % Button pushed function: Condition_browse
        function Condition_browsePushed(app, event)
            app.go_on.Enable = 'off';

            [file,path]=uigetfile({'*.xlsx';'*.mat'},'Select One or More Files','MultiSelect','off');
            if isequal(file,0)
                app.condition_display.Value=" ";
            else
                app.condition_display.Value=fullfile(path,file);
            end
            assignin('base','Condition_Path',app.condition_display.Value);
            assignin('base','Condition_name',file);
            [~,~,extension] = fileparts(file);
            if strcmpi(extension, '.xlsx')
                Wltp=xlsread(app.condition_display.Value);
                x=Wltp(:,1);
                y=Wltp(:,2);
                plot(app.UIAxes,x,y);
                assignin('base','Condition_length',length(y));
                assignin('base','velocity', y);
            elseif strcmpi(extension, '.mat')
                y=load(app.condition_display.Value);
                var_value=y.velocity;
                x=(1:1:length(var_value));
                plot(app.UIAxes,x,var_value);
                assignin('base','Condition_length',length(var_value));
                assignin('base','velocity',var_value);
            end
            
        end

        % Value changed function: final_front_ratio
        function final_front_ratioValueChanged(app, event)
            value = app.final_front_ratio.Value;
            % app.final_front_ratio=str2double(value);
            assignin('base','final_front_ratio',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: final_front_eff
        function final_front_effValueChanged(app, event)
            value = app.final_front_eff.Value;
            % app.final_front_eff=str2double(value);
            assignin('base','final_front_eff',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: final_rear_eff
        function final_rear_effValueChanged(app, event)
            value = app.final_rear_eff.Value;
            % app.final_rear_eff=str2double(value);
            theta_1=atan(value);
            assignin('base','final_rear_eff',theta_1);
            app.go_on.Enable = 'off';
        end

        % Value changed function: mass_full
        function mass_fullValueChanged(app, event)
            value = app.mass_full.Value;
            % app.mass_full=str2double(value);
            assignin('base','mass_full',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: mass_empty
        function mass_emptyValueChanged(app, event)
            value = app.mass_empty.Value;
            % app.mass_empty=str2double(value);
            assignin('base','mass_empty',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: wind_area
        function wind_areaValueChanged(app, event)
            value = app.wind_area.Value;
            % app.wind_area=str2double(value);
            assignin('base','wind_area',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: rolling_coeff
        function rolling_coeffValueChanged(app, event)
            value = app.rolling_coeff.Value;
            % app.rolling_coeff=str2double(value);
            assignin('base','rolling_coeff',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: slope
        function slopeValueChanged(app, event)
            value = app.slope.Value;
            % app.slope=str2double(value);
            assignin('base','slope',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: air_density
        function air_densityValueChanged(app, event)
            value = app.air_density.Value;
            % app.air_density=str2double(value);
            assignin('base','air_density',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: wind_coeff
        function wind_coeffValueChanged(app, event)
            value = app.wind_coeff.Value;
            % app.wind_coeff=str2double(value);
            assignin('base','wind_coeff',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: final_rear_ratio
        function final_rear_ratioValueChanged(app, event)
            value = app.final_rear_ratio.Value;
            % app.final_rear_ratio=str2double(value);
            assignin('base','final_rear_ratio',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: rotation_converse_coeff
        function rotation_converse_coeffValueChanged(app, event)
            value = app.rotation_converse_coeff.Value;
            % app.rotation_converse_coeff=str2double(value);
            assignin('base','rotation_converse_coeff',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: adhesion_coeff
        function adhesion_coeffValueChanged(app, event)
            value = app.adhesion_coeff.Value;
            % app.adhesion_coeff=str2double(value);
            assignin('base','adhesion_coeff',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: gravity_acc
        function gravity_accValueChanged(app, event)
            value = app.gravity_acc.Value;
            % app.gravity_acc=str2double(value);
            assignin('base','gravity_acc',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: radius_wheel
        function radius_wheelValueChanged(app, event)
            value = app.radius_wheel.Value;
            % app.radius_wheel=str2double(value);
            assignin('base','radius_wheel',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: p_auxiliary
        function p_auxiliaryValueChanged(app, event)
            value = app.p_auxiliary.Value;
            % app.p_auxiliary=str2double(value);
            assignin('base','p_auxiliary',value);
            app.go_on.Enable = 'off';
        end

        % Button pushed function: re_turn
        function re_turnPushed(app, event)
            delete(app.UIFigure);
            run Optimal_Strategy/CheXing_Choose.mlapp;

        end

        % Button pushed function: reset
        function resetPushed(app, event)
            evalin("base","clear");
            delete(app.UIFigure);
            run ./Graphical_User_Interface/vehicle_parameter.mlapp;
            
        end

        % Value changed function: wheel_base
        function wheel_baseValueChanged(app, event)
            value = app.wheel_base.Value;

            assignin('base','wheel_base',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: forward_distance
        function forward_distanceValueChanged(app, event)
            value = app.forward_distance.Value;
            assignin('base','forward_distance',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: hg_full
        function hg_fullValueChanged(app, event)
            value = app.hg_full.Value;
            assignin('base','hg_full',value);
            app.go_on.Enable = 'off';
        end

        % Value changed function: hg_empty
        function hg_emptyValueChanged(app, event)
            value = app.hg_empty.Value;
            assignin('base','hg_empty',value);
            app.go_on.Enable = 'off';
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            selected = app.DropDown.Value;
            if selected == "使用满载质量计算"
                value = app.mass_full.Value;
                app.kgEditField.Value=value;
                app.kgEditField_2.Value=0;
                hgvalue=app.hg_full.Value;
                 app.mEditField.Value=hgvalue;

            elseif selected=="使用测试质量计算"
                value = app.kgEditField_2.Value;
                app.mEditField.Value=0;
                app.kgEditField.Value=0;
                hgvalue=app.mEditField.Value;
              
            else
                value=0;
                hgvalue=0;
            end
          
            assignin('base','m_calcu',value);
            assignin('base','hg_calcu',hgvalue);
        end

        % Callback function
        function MEditFieldValueChanged(app, event)
             app.MEditField.Value='禁止手动输入';
             app.MEditField.Editable="off";
        end

        % Button pushed function: confirm
        function confirmPushed(app, event)
            selected = app.DropDown.Value;
           if selected == "使用满载质量计算"
                value = app.mass_full.Value;
                app.kgEditField.Value=value;
                app.kgEditField_2.Value=0;
                hgvalue=app.hg_full.Value;
                 app.mEditField.Value=hgvalue;
            elseif selected=="使用测试质量计算"
                value = app.kgEditField_2.Value;
               
                app.kgEditField.Value=0;
                hgvalue=app.mEditField.Value;
              
            else
                 value=0;
                hgvalue=0;
            end
         
          %  app.mEditField.Value=hgvalue;
           % app.kEditField.Value = value_str;
            assignin('base','m_calcu',value);
           % app.kEditField.Value = value_hg;
            assignin('base','hg_calcu',hgvalue);
            app.go_on.Enable = 'on';
        end

        % Callback function
        function hgEditFieldValueChanged(app, event)
             app.hgEditField.Value='禁止手动输入';
             app.hgEditField.Editable="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '测试工况输出';
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, '时间(/s)')
            ylabel(app.UIAxes, '速度(km/h)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Label_15 = uilabel(app.Panel_3);
            app.Label_15.Text = '工况数据文件上传';
            app.condition_display = uieditfield(app.Panel_3, 'text');
            app.Condition_browse = uibutton(app.Panel_3, 'push');
            app.Condition_browse.ButtonPushedFcn = createCallbackFcn(app, @Condition_browsePushed, true);
            app.Condition_browse.Text = '浏览';
            app.xlsxmatLabel = uilabel(app.Panel_3);
            app.xlsxmatLabel.Text = '支持xlsx文件和mat文件';
            app.go_on = uibutton(app.UIFigure, 'push');
            app.go_on.ButtonPushedFcn = createCallbackFcn(app, @go_onPushed, true);
            app.go_on.Enable = 'off';
            app.go_on.Text = '继续';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Text = '重置';
            app.re_turn = uibutton(app.UIFigure, 'push');
            app.re_turn.ButtonPushedFcn = createCallbackFcn(app, @re_turnPushed, true);
            app.re_turn.Text = '返回';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Title = '主减速器参数';
            app.GridLayout = uigridlayout(app.Panel_7);
            app.final_front_ratio = uieditfield(app.GridLayout, 'numeric');
            app.final_front_ratio.ValueChangedFcn = createCallbackFcn(app, @final_front_ratioValueChanged, true);
            app.final_front_ratio.Value = 12.7;
            app.Label_24 = uilabel(app.GridLayout);
            app.Label_24.Text = '前减速器传动比';
            app.final_front_eff = uieditfield(app.GridLayout, 'numeric');
            app.final_front_eff.Limits = [0 Inf];
            app.final_front_eff.ValueChangedFcn = createCallbackFcn(app, @final_front_effValueChanged, true);
            app.final_front_eff.Value = 0.95;
            app.Label_25 = uilabel(app.GridLayout);
            app.Label_25.Text = '前减速器传动效率';
            app.final_rear_ratio = uieditfield(app.GridLayout, 'numeric');
            app.final_rear_ratio.Limits = [0 Inf];
            app.final_rear_ratio.ValueChangedFcn = createCallbackFcn(app, @final_rear_ratioValueChanged, true);
            app.final_rear_ratio.Value = 12.7;
            app.Label_26 = uilabel(app.GridLayout);
            app.Label_26.Text = '后减速器传动比';
            app.final_rear_eff = uieditfield(app.GridLayout, 'numeric');
            app.final_rear_eff.ValueChangedFcn = createCallbackFcn(app, @final_rear_effValueChanged, true);
            app.final_rear_eff.Value = 0.95;
            app.Label_27 = uilabel(app.GridLayout);
            app.Label_27.Text = '后减速器传动效率';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Title = '整车参数';
            app.GridLayout2 = uigridlayout(app.Panel_8);
            app.mass_full = uieditfield(app.GridLayout2, 'numeric');
            app.mass_full.Limits = [0 Inf];
            app.mass_full.ValueChangedFcn = createCallbackFcn(app, @mass_fullValueChanged, true);
            app.mass_full.Value = 4500;
            app.EditField_2Label_3 = uilabel(app.GridLayout2);
            app.EditField_2Label_3.Text = '爬坡用满载质量(kg)';
            app.mass_empty = uieditfield(app.GridLayout2, 'numeric');
            app.mass_empty.Limits = [0 Inf];
            app.mass_empty.ValueChangedFcn = createCallbackFcn(app, @mass_emptyValueChanged, true);
            app.mass_empty.Value = 2840;
            app.EditField_3Label_3 = uilabel(app.GridLayout2);
            app.EditField_3Label_3.Text = '空载质量(kg)';
            app.wind_area = uieditfield(app.GridLayout2, 'numeric');
            app.wind_area.ValueChangedFcn = createCallbackFcn(app, @wind_areaValueChanged, true);
            app.wind_area.Value = 2.22;
            app.m2Label = uilabel(app.GridLayout2);
            app.m2Label.Text = '迎风面积(m^2)';
            app.gravity_acc = uieditfield(app.GridLayout2, 'numeric');
            app.gravity_acc.ValueChangedFcn = createCallbackFcn(app, @gravity_accValueChanged, true);
            app.gravity_acc.Value = 9.8;
            app.Label_16 = uilabel(app.GridLayout2);
            app.Label_16.Text = '重力加速度';
            app.slope = uieditfield(app.GridLayout2, 'numeric');
            app.slope.ValueChangedFcn = createCallbackFcn(app, @slopeValueChanged, true);
            app.EditField_3Label_4 = uilabel(app.GridLayout2);
            app.EditField_3Label_4.Text = '坡度(°)';
            app.radius_wheel = uieditfield(app.GridLayout2, 'numeric');
            app.radius_wheel.ValueChangedFcn = createCallbackFcn(app, @radius_wheelValueChanged, true);
            app.radius_wheel.Value = 0.346;
            app.EditField_2Label_4 = uilabel(app.GridLayout2);
            app.EditField_2Label_4.Text = '车轮滚动半径(m)';
            app.rolling_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.rolling_coeff.ValueChangedFcn = createCallbackFcn(app, @rolling_coeffValueChanged, true);
            app.rolling_coeff.Value = 0.01;
            app.EditField_4Label = uilabel(app.GridLayout2);
            app.EditField_4Label.Text = '滚动阻力系数';
            app.p_auxiliary = uieditfield(app.GridLayout2, 'numeric');
            app.p_auxiliary.ValueChangedFcn = createCallbackFcn(app, @p_auxiliaryValueChanged, true);
            app.Label_17 = uilabel(app.GridLayout2);
            app.Label_17.Text = '附件功率(W)';
            app.wind_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.wind_coeff.ValueChangedFcn = createCallbackFcn(app, @wind_coeffValueChanged, true);
            app.wind_coeff.Value = 0.188;
            app.EditField_6Label = uilabel(app.GridLayout2);
            app.EditField_6Label.Text = '风阻系数';
            app.air_density = uieditfield(app.GridLayout2, 'numeric');
            app.air_density.ValueChangedFcn = createCallbackFcn(app, @air_densityValueChanged, true);
            app.air_density.Value = 1.2;
            app.kgLabel = uilabel(app.GridLayout2);
            app.kgLabel.Text = '空气密度(kg/m^3)';
            app.rotation_converse_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.rotation_converse_coeff.ValueChangedFcn = createCallbackFcn(app, @rotation_converse_coeffValueChanged, true);
            app.rotation_converse_coeff.Value = 1.08;
            app.Label_18 = uilabel(app.GridLayout2);
            app.Label_18.Text = '旋转质量系数';
            app.adhesion_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.adhesion_coeff.ValueChangedFcn = createCallbackFcn(app, @adhesion_coeffValueChanged, true);
            app.adhesion_coeff.Value = 0.8;
            app.Label_19 = uilabel(app.GridLayout2);
            app.Label_19.Text = '路面附着系数';
            app.Label_20 = uilabel(app.GridLayout2);
            app.Label_20.Text = '轴距(m)';
            app.wheel_base = uieditfield(app.GridLayout2, 'numeric');
            app.wheel_base.ValueChangedFcn = createCallbackFcn(app, @wheel_baseValueChanged, true);
            app.wheel_base.Value = 3.76;
            app.Label_21 = uilabel(app.GridLayout2);
            app.Label_21.Text = '前轴到质心距离(m)';
            app.forward_distance = uieditfield(app.GridLayout2, 'numeric');
            app.forward_distance.ValueChangedFcn = createCallbackFcn(app, @forward_distanceValueChanged, true);
            app.forward_distance.Value = 1.735;
            app.Label_22 = uilabel(app.GridLayout2);
            app.Label_22.Text = '质心高度（满载）(m)';
            app.hg_full = uieditfield(app.GridLayout2, 'numeric');
            app.hg_full.ValueChangedFcn = createCallbackFcn(app, @hg_fullValueChanged, true);
            app.hg_full.Value = 0.88;
            app.Label_23 = uilabel(app.GridLayout2);
            app.Label_23.Text = '质心高度（空载）';
            app.hg_empty = uieditfield(app.GridLayout2, 'numeric');
            app.hg_empty.ValueChangedFcn = createCallbackFcn(app, @hg_emptyValueChanged, true);
            app.hg_empty.Value = 0.77;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '质量确认';
            app.Label_28 = uilabel(app.ButtonGroup);
            app.Label_28.Text = '选择';
            app.DropDown = uidropdown(app.ButtonGroup);
            app.DropDown.Items = {'请下拉选择', '使用满载质量计算', '使用测试质量计算'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '使用满载质量计算';
            app.confirm = uibutton(app.ButtonGroup, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmPushed, true);
            app.confirm.Text = '确认';
            app.kgEditFieldLabel = uilabel(app.ButtonGroup);
            app.kgEditFieldLabel.Text = '满载质量(kg)=';
            app.kgEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.kgEditField_2Label = uilabel(app.ButtonGroup);
            app.kgEditField_2Label.Text = '测试质量(kg)=';
            app.kgEditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.mEditFieldLabel = uilabel(app.ButtonGroup);
            app.mEditFieldLabel.Text = '质心高度(m)=';
            app.mEditField = uieditfield(app.ButtonGroup, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehicle_parameter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end